classdef InVivoKineticsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InVivoKineticsUIFigure          matlab.ui.Figure
        MainTabGroup                    matlab.ui.container.TabGroup
        EventlabellerTab                matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        EventlabellersettingsTab        matlab.ui.container.TabGroup
        LabellingcontrolsTab            matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        GotostartButton                 matlab.ui.control.Button
        MoreinfoButton                  matlab.ui.control.Button
        backwardButton                  matlab.ui.control.Button
        forwardButton                   matlab.ui.control.Button
        IllustrationsettingsPanel       matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        SaveeveryviewedmoleculeCheckBox  matlab.ui.control.CheckBox
        IllustrateafterlabellingCheckBox  matlab.ui.control.CheckBox
        SavealldatawithvideoCheckBox    matlab.ui.control.CheckBox
        PlayexportedvideoCheckBox       matlab.ui.control.CheckBox
        SaveillustrationButton          matlab.ui.control.Button
        DiscardmoleculeButton           matlab.ui.control.Button
        BeginButton                     matlab.ui.control.Button
        UndolabelButton                 matlab.ui.control.Button
        GotoendButton                   matlab.ui.control.Button
        Event_label_buttons             matlab.ui.container.Panel
        PlotsettingsTab                 matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        ReferencelinecolourDropDown     matlab.ui.control.DropDown
        ReferencelinecolourDropDownLabel  matlab.ui.control.Label
        IndicatorthicknessSpinner       matlab.ui.control.Spinner
        IndicatorthicknessSpinnerLabel  matlab.ui.control.Label
        ReferencelinethicknessSpinner   matlab.ui.control.Spinner
        ReferencelinethicknessSpinnerLabel  matlab.ui.control.Label
        ClearreferencelinesButton       matlab.ui.control.Button
        AddreferencelineButton          matlab.ui.control.Button
        ReferencelineEditField          matlab.ui.control.NumericEditField
        ReferencelineEditFieldLabel     matlab.ui.control.Label
        SetlowerlimttozeroCheckBox      matlab.ui.control.CheckBox
        FeaturestoplotinexternalwindowLabel  matlab.ui.control.Label
        PlotMoreFeatures                matlab.ui.container.CheckBoxTree
        FeatureDropDown                 matlab.ui.control.DropDown
        FeatureDropDownLabel            matlab.ui.control.Label
        LinecolourDropDown              matlab.ui.control.DropDown
        LinecolourDropDownLabel         matlab.ui.control.Label
        LinethicknessSpinner            matlab.ui.control.Spinner
        LinethicknessSpinnerLabel       matlab.ui.control.Label
        VideodisplayTab                 matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        DynamicrangemodeDropDown        matlab.ui.control.DropDown
        DynamicrangemodeDropDownLabel   matlab.ui.control.Label
        BrightfieldoverlayCheckBox      matlab.ui.control.CheckBox
        FluorescenceimageCheckBox       matlab.ui.control.CheckBox
        OverlayopacityEditField         matlab.ui.control.NumericEditField
        OverlayopacityEditFieldLabel    matlab.ui.control.Label
        DynamicrangehighEditField       matlab.ui.control.NumericEditField
        DynamicrangehighEditFieldLabel  matlab.ui.control.Label
        DynamicrangelowEditField        matlab.ui.control.NumericEditField
        DynamicrangelowEditFieldLabel   matlab.ui.control.Label
        ExportframerateEditField        matlab.ui.control.NumericEditField
        ExportvideoframerateHzEditFieldLabel  matlab.ui.control.Label
        VideoupscalefactorSpinner       matlab.ui.control.Spinner
        VideoupscalefactorSpinnerLabel  matlab.ui.control.Label
        TrajectoryillustrationTab       matlab.ui.container.Tab
        GridLayout26                    matlab.ui.container.GridLayout
        TrackstyleDropDown              matlab.ui.control.DropDown
        TrackstyleDropDownLabel         matlab.ui.control.Label
        ScalebarlengthSpinner           matlab.ui.control.Spinner
        ScalebarlengthmSpinnerLabel     matlab.ui.control.Label
        ScalebarpositionDropDown        matlab.ui.control.DropDown
        ScalebarpositionDropDownLabel   matlab.ui.control.Label
        ScalebarcolourDropDown          matlab.ui.control.DropDown
        ScalebarcolourDropDownLabel     matlab.ui.control.Label
        ScalebarCheckBox                matlab.ui.control.CheckBox
        AutosaveTab                     matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        NumberofautosvefilestokeepSpinner  matlab.ui.control.Spinner
        NumberofautosvefilestokeepSpinnerLabel  matlab.ui.control.Label
        AutosavefrequencytrajectoriesSpinner  matlab.ui.control.Spinner
        AutosavefrequencytrajectoriesSpinnerLabel  matlab.ui.control.Label
        MolIDTextArea                   matlab.ui.control.TextArea
        MolIDLabel                      matlab.ui.control.Label
        CellIDTextArea                  matlab.ui.control.TextArea
        CellIDTextAreaLabel             matlab.ui.control.Label
        Slider_event_labeller           matlab.ui.control.Slider
        UIAxes_event_labeller           matlab.ui.control.UIAxes
        UIAxes_image_event_labeller     matlab.ui.control.UIAxes
        UIAxes_event_labeller_status    matlab.ui.control.UIAxes
        UIAxes_labelling_progress       matlab.ui.control.UIAxes
        UIAxes_event_labeller_mesh      matlab.ui.control.UIAxes
        InsightsTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        TabGroup4                       matlab.ui.container.TabGroup
        DatasetoverviewTab              matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        MaximumstepsizesSpinner         matlab.ui.control.Spinner
        MaximumstepsizesSpinnerLabel    matlab.ui.control.Label
        DisplaystepsizeprofileButton    matlab.ui.control.Button
        StatetransitiondiagramButton    matlab.ui.control.Button
        DescriptivestatsTable           matlab.ui.control.Table
        UpdatedescriptivestatisticsButton  matlab.ui.control.Button
        EventhistogramsTab              matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        HistogramdatatoexportDropDown   matlab.ui.control.DropDown
        HistogramdatatoexportDropDownLabel  matlab.ui.control.Label
        SourceDropDown                  matlab.ui.control.DropDown
        SourceDropDownLabel             matlab.ui.control.Label
        PlotallstepsizesButton          matlab.ui.control.Button
        PlotalltrajectorylengthsButton  matlab.ui.control.Button
        BinwidthsSpinner                matlab.ui.control.Spinner
        BinwidthsSpinnerLabel           matlab.ui.control.Label
        TruncationDropDown              matlab.ui.control.DropDown
        TruncationDropDownLabel         matlab.ui.control.Label
        EventstoviewDropDown            matlab.ui.control.DropDown
        EventstoviewDropDownLabel       matlab.ui.control.Label
        ExporthistogramButton           matlab.ui.control.Button
        DisplaystatisticsButton         matlab.ui.control.Button
        DiffusionanalysisTab            matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        LocalisationerrormSpinner       matlab.ui.control.Spinner
        LocalisationerrormSpinnerLabel  matlab.ui.control.Label
        DcalculationmethodDropDown      matlab.ui.control.DropDown
        DcalculationmethodDropDownLabel  matlab.ui.control.Label
        ProcessaveragesDropDown         matlab.ui.control.DropDown
        ProcessaveragesDropDownLabel    matlab.ui.control.Label
        NumberofstepsforDcalculationSpinner  matlab.ui.control.Spinner
        NumberofstepsforDcalculationSpinnerLabel  matlab.ui.control.Label
        LagtimetodisplaysSpinner        matlab.ui.control.Spinner
        LagtimetodisplaysSpinnerLabel   matlab.ui.control.Label
        MinimumlocalisationsforMSDSpinner  matlab.ui.control.Spinner
        MinimumlocalisationsforMSDSpinnerLabel  matlab.ui.control.Label
        SourceoflabelleddataDropDown    matlab.ui.control.DropDown
        SourceoflabelleddataDropDownLabel  matlab.ui.control.Label
        ExportMSDdataCheckBox           matlab.ui.control.CheckBox
        ComputeDButton                  matlab.ui.control.Button
        GalleryviewerTab                matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        GeneratePDFsofallmoleculesButton  matlab.ui.control.Button
        EventoverlayerTab               matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        EventoverlayerSOI               matlab.ui.control.DropDown
        StateofinterestLabel            matlab.ui.control.Label
        EventhistogramvideosettingsPanel  matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        GeneratevideoCheckBox           matlab.ui.control.CheckBox
        EntriesorexitsDropDown          matlab.ui.control.DropDown
        EntriesorexitsDropDownLabel     matlab.ui.control.Label
        VideoendrelativetoeventsSpinner  matlab.ui.control.Spinner
        VideoendrelativetoeventsSpinnerLabel  matlab.ui.control.Label
        VideostartrelativetoeventsSpinner  matlab.ui.control.Spinner
        VideostartrelativetoeventsSpinnerLabel  matlab.ui.control.Label
        EventhistogramvideoupperlimitSpinner  matlab.ui.control.Spinner
        EventhistogramvideoupperlimitSpinnerLabel  matlab.ui.control.Label
        EventhistogramvideobinwidthSpinner  matlab.ui.control.Spinner
        EventhistogramvideobinwidthSpinnerLabel  matlab.ui.control.Label
        ExporteventoverlaysButton       matlab.ui.control.Button
        DisplayalloverlayedeventsButton  matlab.ui.control.Button
        OverlayplotsettingsPanel        matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        Overlayerstyle                  matlab.ui.control.DropDown
        StyleDropDownLabel              matlab.ui.control.Label
        overlayerylimlow                matlab.ui.control.NumericEditField
        yaxisEditFieldLabel             matlab.ui.control.Label
        overlayerxlimlow                matlab.ui.control.NumericEditField
        xaxisEditFieldLabel             matlab.ui.control.Label
        UpdatelimitsButton              matlab.ui.control.Button
        overlayerxlimhigh               matlab.ui.control.NumericEditField
        overlayerylimhigh               matlab.ui.control.NumericEditField
        UIAxes_compiled_events          matlab.ui.control.UIAxes
        MLclassificationTab             matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        MLControlTabs                   matlab.ui.container.TabGroup
        GeneratetrainingdataTab         matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        SpanlengthSpinner               matlab.ui.control.Spinner
        SpanlengthSpinnerLabel          matlab.ui.control.Label
        SpantypeDropDown                matlab.ui.control.DropDown
        SpantypeDropDownLabel           matlab.ui.control.Label
        IgnorerowsfromendSpinner        matlab.ui.control.Spinner
        IgnorerowsfromendSpinnerLabel   matlab.ui.control.Label
        IgnorerowsfromstartSpinner      matlab.ui.control.Spinner
        IgnorerowsfromstartSpinnerLabel  matlab.ui.control.Label
        FeaturescalingDropDown          matlab.ui.control.DropDown
        FeaturescalingDropDownLabel     matlab.ui.control.Label
        TrainingvalidationtestsplitEditField  matlab.ui.control.EditField
        TrainingvalidationtestsplitEditFieldLabel  matlab.ui.control.Label
        ShufflingmethodDropDown         matlab.ui.control.DropDown
        ShufflingmethodLabel            matlab.ui.control.Label
        KnockoutzerosCheckBox           matlab.ui.control.CheckBox
        ExporttrainingdataButton        matlab.ui.control.Button
        GeneratetrainingsetButton       matlab.ui.control.Button
        TrainmodelsTab                  matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        PerformcrossvalidationButton    matlab.ui.control.Button
        UsebootstrappingCheckBox        matlab.ui.control.CheckBox
        BootstrapsamplesSpinner         matlab.ui.control.Spinner
        BootstrapsamplesSpinnerLabel    matlab.ui.control.Label
        KfoldsSpinner                   matlab.ui.control.Spinner
        KfoldsSpinnerLabel              matlab.ui.control.Label
        ExportmodelButton               matlab.ui.control.Button
        ModelexportformatDropDown       matlab.ui.control.DropDown
        ModelexportformatDropDownLabel  matlab.ui.control.Label
        ModeltypeDropDown               matlab.ui.control.DropDown
        ModeltypeDropDownLabel          matlab.ui.control.Label
        ModelsettingsButton             matlab.ui.control.Button
        SavemodelButton                 matlab.ui.control.Button
        TrainMLmodelButton              matlab.ui.control.Button
        VisualisationTab                matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        MaxpointstodisplaySpinner       matlab.ui.control.Spinner
        MaxpointstodisplaySpinnerLabel  matlab.ui.control.Label
        PlotcontrolsPanel               matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        ZaxisLabel                      matlab.ui.control.Label
        YaxisLabel                      matlab.ui.control.Label
        XaxisLabel                      matlab.ui.control.Label
        ZLimHighSpinner                 matlab.ui.control.Spinner
        ZLimLowSpinner                  matlab.ui.control.Spinner
        YLimHighSpinner                 matlab.ui.control.Spinner
        YLimLowSpinner                  matlab.ui.control.Spinner
        XLimHighSpinner                 matlab.ui.control.Spinner
        XLimLowSpinner                  matlab.ui.control.Spinner
        XYButton                        matlab.ui.control.Button
        XZButton                        matlab.ui.control.Button
        YZButton                        matlab.ui.control.Button
        PanButton                       matlab.ui.control.Button
        ZoomButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        RotateButton                    matlab.ui.control.Button
        DatatodisplayDropDown           matlab.ui.control.DropDown
        DatatodisplayDropDownLabel      matlab.ui.control.Label
        ZaxisDropDown                   matlab.ui.control.DropDown
        ZaxisDropDownLabel              matlab.ui.control.Label
        YaxisDropDown                   matlab.ui.control.DropDown
        YaxisDropDownLabel              matlab.ui.control.Label
        XaxisDropDown                   matlab.ui.control.DropDown
        XaxisDropDownLabel              matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.Button
        ExplainabilityTab               matlab.ui.container.Tab
        GridLayout24                    matlab.ui.container.GridLayout
        EvaluateindividualframesButton  matlab.ui.control.Button
        DisplaySHAPvaluetableButton     matlab.ui.control.Button
        SHAPvaluedisplayDropDown        matlab.ui.control.DropDown
        SHAPvaluedisplayDropDownLabel   matlab.ui.control.Label
        NumberofexamplesSpinner         matlab.ui.control.Spinner
        NumberofexamplesSpinnerLabel    matlab.ui.control.Label
        ComputeglobalfeatureimportanceButton  matlab.ui.control.Button
        ClassifyusingmodelTab           matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        DatasetforclassificationTextArea  matlab.ui.control.TextArea
        DatasetforclassificationTextAreaLabel  matlab.ui.control.Label
        CurrentmodelfileTextArea        matlab.ui.control.TextArea
        CurrentmodelfileTextAreaLabel   matlab.ui.control.Label
        DisplaydatasetoverviewButton    matlab.ui.control.Button
        DisplaymodeldetailsButton       matlab.ui.control.Button
        LoaddatasetforclassificationButton  matlab.ui.control.Button
        ClassifywithmodelButton         matlab.ui.control.Button
        LoadexistingmodelButton         matlab.ui.control.Button
        EnsemblelearningTab             matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        FeaturesLabel                   matlab.ui.control.Label
        MLfeatures                      matlab.ui.container.CheckBoxTree
        MLDataView                      matlab.ui.control.UIAxes
        textout                         matlab.ui.control.TextArea
        SideBarTabGroup                 matlab.ui.container.TabGroup
        LoadSaveTab                     matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        SaveanalysisButton              matlab.ui.control.Button
        ClearanalysisButton             matlab.ui.control.Button
        SaveconfigurationButton         matlab.ui.control.Button
        LoadconfigurationButton         matlab.ui.control.Button
        UserEditField                   matlab.ui.control.EditField
        UserLabel                       matlab.ui.control.Label
        FileoverviewTextArea            matlab.ui.control.TextArea
        FileoverviewTextAreaLabel       matlab.ui.control.Label
        CurrentlyloadeddatasetTextArea  matlab.ui.control.TextArea
        CurrentlyloadeddatasetTextAreaLabel  matlab.ui.control.Label
        LoadanalysisButton              matlab.ui.control.Button
        LoaddataButton                  matlab.ui.control.StateButton
        PrepareTab                      matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        UndriftingmethodDropDown        matlab.ui.control.DropDown
        UndriftingmethodDropDownLabel   matlab.ui.control.Label
        IllustrationborderSpinner       matlab.ui.control.Spinner
        IllustrationborderpxSpinnerLabel  matlab.ui.control.Label
        FiltertoleranceSpinner          matlab.ui.control.Spinner
        FiltertoleranceSpinnerLabel     matlab.ui.control.Label
        FilteragainstDropDown           matlab.ui.control.DropDown
        FilteragainstDropDownLabel      matlab.ui.control.Label
        FilteringmethodDropDown         matlab.ui.control.DropDown
        FilteringmethodDropDownLabel    matlab.ui.control.Label
        MemoryparameterSpinner          matlab.ui.control.Spinner
        MemoryparameterSpinnerLabel     matlab.ui.control.Label
        MinimumtracklengthSpinner       matlab.ui.control.Spinner
        MinimumtracklengthSpinnerLabel  matlab.ui.control.Label
        IntensityboxpxSpinner           matlab.ui.control.Spinner
        IntensityboxpxSpinnerLabel      matlab.ui.control.Label
        TrackbufferframesSpinner        matlab.ui.control.Spinner
        TrackbufferframesSpinnerLabel   matlab.ui.control.Label
        PixelscaleEditField             matlab.ui.control.NumericEditField
        PixelscalenmEditFieldLabel      matlab.ui.control.Label
        PreparedataforMLclassificationButton  matlab.ui.control.StateButton
        PreparedataforkineticsanalysisButton  matlab.ui.control.StateButton
        NotesTab                        matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        DataanalysisnotesTextArea       matlab.ui.control.TextArea
        DataanalysisnotesTextAreaLabel  matlab.ui.control.Label
        HelpaboutTab                    matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        VisittheGitHubrepositoryButton  matlab.ui.control.Button
        QuickstartguideButton           matlab.ui.control.Button
        TextArea2                       matlab.ui.control.TextArea
        Authorship                      matlab.ui.control.TextArea
        Label                           matlab.ui.control.Label
        OpenPDFmanualButton             matlab.ui.control.Button
    end

    properties (Access = public)
        %declare the movie_data property inside the app object to later use to handle data
        movie_data = struct();
    end
    
    properties (Access = private)
        software_version = '0.8';
        user_name        = 'Oliver Pambos';                     %Assigned user's name for pre-release access
        user_email       = 'oliver.pambos@physics.ox.ac.uk';    %Assigned user's email
    end
    
    methods (Access = private)
        
        function agreed = displayToSDialog(app)
        %Force user to respond to the terms of service every time the
        %application is launched, Oliver Pambos, 17/11/2023.
        %
        %This was necessitated by a collaborator lab this week using
        %another software system I custom built for them, publishing
        %without properly assigning credit
            
            ToS_text = [
                "By using this software, you agree to be bound by the following terms and conditions.",
                "",
                "This software is provided to you, the user, and is not tied to any specific machine or installation. You agree not to distribute, share, or allow others to use this software, regardless of whether they are using your machine. Any use of this software must be explicitly authorized by Oliver James Pambos, Department of Physics, University of Oxford, UK.",
                "",
                "This software is an unpublished, pre-release version. If you use this application for analysis, exploration of data, or gaining insights that may influence your work, prior to its public release, you agree to include Oliver James Pambos as a co-author on any resulting publications. This co-authorship agreement applies regardless of whether this software is used in the final analysis.",
                "",
                "Upon the public release of this software, the terms of use will change. The publicly available version can be downloaded from the designated online repository and will be subject to different conditions, potentially including the requirement for citation of the original paper.",
                "",
                "Before using this pre-release version of the software, it is mandatory for you, the user, to ensure that all terms and conditions, including those related to authorship, have been reviewed and agreed upon by the person in charge of your laboratory or the individual responsible for authorship decisions at your institution or company. By proceeding to use this software, you confirm that such agreement has been obtained and that all parties involved acknowledge and consent to these terms.",
                "",
                "It is important to note that if you have used this pre-release version for any form of data analysis, exploration, or gained insights that contribute to your research, you are obligated to honor this co-authorship agreement. This obligation persists even if you subsequently use the publicly released version for further analysis or for the final version of your research output. In any disputes regarding the use of this software, the benefit of the doubt will be given to the author, Oliver James Pambos.",
                "",
                "Your use of this pre-release version, or any of its code, signifies your acceptance of these terms and conditions.",
            ];
            
            %generate the dialog window
            app_pos       = app.InVivoKineticsUIFigure.Position;
            dialog_wid    = 500;
            dialog_height = 400;
            dlg           = uifigure('Name', 'Terms of Service', 'WindowStyle', 'modal', 'Position', [app_pos(1) + (app_pos(3) - dialog_wid) / 2, app_pos(2) + (app_pos(4) - dialog_height) / 2, dialog_wid, dialog_height], 'Resize', 'off');
            txt_area      = uitextarea(dlg, 'Value', ToS_text, 'Editable', 'off', 'Position', [10, 60, dialog_wid - 20, dialog_height - 100], 'FontSize', 14);
            btn_agree     = uibutton(dlg, 'Text', 'I Agree', 'Position', [50, 10, 100, 30], 'ButtonPushedFcn', @(btn,event) uiresume(dlg));
            btn_disagree  = uibutton(dlg, 'Text', 'I Disagree', 'Position', [dialog_wid - 150, 10, 100, 30], 'ButtonPushedFcn', @(btn,event) close(dlg));
            
            uiwait(dlg);
            agreed = isvalid(dlg);
            if agreed
                close(dlg);
            end
        end
       
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %display Terms of Service
            tos_agreed = app.displayToSDialog();
            if ~tos_agreed
                close(app.InVivoKineticsUIFigure);
                return;
            end
            
        end

        % Value changed function: LoaddataButton
        function LoaddataButtonValueChanged(app, event)
            %load a new data file - currently restricted to the LoColi pipeline
            app.textout.Value = 'Please select the LoColi data file';
            [temp_name, temp_path] = uigetfile();
            if temp_name ~= 0
                temp_struct     = load([temp_path '/' temp_name]);
                app.movie_data  = temp_struct.movie_data;
                figure(app.InVivoKineticsUIFigure);
                app.textout.Value = 'Please choose a sensible name for the dataset';
                app.movie_data.params.title = inputdlg({'Please choose a sensible name for the dataset'}, 'Name this dataset', [1 80], {temp_name});
                app.CurrentlyloadeddatasetTextArea.Value = app.movie_data.params.title;
            end
        end

        % Value changed function: PreparedataforkineticsanalysisButton
        function PreparedataforkineticsanalysisButtonValueChanged(app, event)
            %obtain parameters from GUI inputs - not placed in startupFcn because the contents of app.movie_data is overwritten by the LoColi file import
            app.movie_data.params.px_scale                  = app.PixelscaleEditField.Value;            %pixel scale (nm/pixel)
            app.movie_data.params.track_buffer              = app.TrackbufferframesSpinner.Value;       %minimum number of empty frames between tracks in same cell
            app.movie_data.params.I_border                  = app.IntensityboxpxSpinner.Value;          %size of border around localisation to obtain fluorescence intensity (not currently used)
            app.movie_data.params.dr_auto                   = app.DynamicrangemodeDropDown.Value;       %mode used to set dynamic range of fluor data in illustrations ('auto' or 'manual')
            app.movie_data.params.dr_lo                     = app.DynamicrangelowEditField.Value;       %lower limit of dynamic range of fluor data in 'manual' mode
            app.movie_data.params.dr_hi                     = app.DynamicrangehighEditField.Value;      %upper limit of dynamic range of fluor data in 'manual' mode
            app.movie_data.params.ill_border                = app.IllustrationborderSpinner.Value;      %size of border around cells in illustrations (pixels)
            app.movie_data.params.overlay_pc                = app.OverlayopacityEditField.Value;        %percentage intensity of brightfield overlay in illustrations
            app.movie_data.params.FOV                       = size(app.movie_data.brightfield_image);   %dimensions of recording
            app.movie_data.params.min_track_len             = app.MinimumtracklengthSpinner.Value;      %minimum length of a track (in localisations) to use in analysis
            app.movie_data.params.video_upscaling_factor    = app.VideoupscalefactorSpinner.Value;      %integer value used to upscale exported videos
            app.movie_data.params.export_frame_rate         = app.ExportframerateEditField.Value;       %frame rate used for export videos
            app.movie_data.params.filtering_method          = app.FilteringmethodDropDown.Value;        %filtering method which determines how filterTracks() handles multiple trajectories with temporal overlaps in a cell
            app.movie_data.params.filter_against            = app.FilteragainstDropDown.Value;          %determine whether to filter basaed on the presence of other tracks or other localisations
            app.movie_data.params.filter_tolerance          = app.FiltertoleranceSpinner.Value;         %maximum number of localisations that can coincide with the track before it is filtered when filtering by localisations
            app.movie_data.params.mem_param                 = app.MemoryparameterSpinner.Value;         %memory parameter used to filter tracks
            app.movie_data.state.play_exported_video        = app.PlayexportedvideoCheckBox.Value;      %play back exported video generated by the event labeller
            app.movie_data.params.hist_bin_wid              = app.BinwidthsSpinner.Value;               %histogram bin width used by the Insights tab
            
            %prepare data
            prepData(app);
            figure(app.InVivoKineticsUIFigure);
            app.textout.Value = 'Data has been prepared.';
        end

        % Value changed function: PixelscaleEditField
        function PixelscaleEditFieldValueChanged(app, event)
            app.movie_data.params.px_scale = app.PixelscaleEditField.Value;
            app.textout.Value = "The pixel scale was changed to " + num2str(app.movie_data.params.px_scale) + " nm/pixel";
        end

        % Value changed function: TrackbufferframesSpinner
        function TrackbufferframesSpinnerValueChanged(app, event)
            app.movie_data.params.track_buffer = app.TrackbufferframesSpinner.Value;
            app.textout.Value = "The minimum temporal separation between tracks in the same cell was changed to " + num2str(app.movie_data.params.track_buffer) + " frames";
        end

        % Value changed function: IntensityboxpxSpinner
        function IntensityboxpxSpinnerValueChanged(app, event)
            app.movie_data.params.I_border = app.IntensityboxpxSpinner.Value;
            app.textout.Value = "The distance between each localisation, and the edge of the intensity profile box was changed to " + num2str(app.movie_data.params.I_border) + " pixels";
        end

        % Value changed function: DynamicrangelowEditField
        function DynamicrangelowEditFieldValueChanged(app, event)
            app.movie_data.params.dr_lo = app.DynamicrangelowEditField.Value;
            
            switch app.DynamicrangemodeDropDown.Value
                case "Auto"
                    app.textout.Value = "The lower limit of the dynamic range of illustrations in manual mode has been set to " + num2str(app.movie_data.params.dr_lo) + "." + newline + ...
                        "As this is currently in Auto mode, you will need to move back into Manual mode to carry out the adjustments.";
                case "Manual"
                    %re-capture video from FITS stack using new dynamic range
                    cell_ID = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.CellID;
                    mol_ID = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.MolID;
                    app.movie_data.current_video = illustrateMol(app.movie_data, cell_ID, mol_ID, 0, app.SaveeveryviewedmoleculeCheckBox.Value, strcat('Cell', num2str(cell_ID), '_Mol', num2str(mol_ID)));
        
                    %display updated video at correct frame
                    imagesc(app.movie_data.current_video(:,:,app.movie_data.state.labeller_frame_video), 'parent', app.UIAxes_image_event_labeller);
                    axis(app.UIAxes_image_event_labeller, 'equal');
                    axis(app.UIAxes_image_event_labeller, 'off');
                    colormap(app.UIAxes_image_event_labeller, gray(256));
                    
                    app.textout.Value = "The lower limit of the dynamic range of illustrations in manual mode has been set to " + num2str(app.movie_data.params.dr_lo) + "." + newline + newline + ...
                        "The video has been updated accordingly.";
                otherwise

            end
        end

        % Value changed function: DynamicrangehighEditField
        function DynamicrangehighEditFieldValueChanged(app, event)
            app.movie_data.params.dr_hi = app.DynamicrangehighEditField.Value;
            
            switch app.DynamicrangemodeDropDown.Value
                case "Auto"
                    app.textout.Value = "The upper limit of the dynamic range of illustrations in manual mode has been set to " + num2str(app.movie_data.params.dr_hi) + "." + newline + ...
                        "As this is currently in Auto mode, you will need to move back into Manual mode to carry out the adjustments.";
                case "Manual"
                    %re-capture video from FITS stack using new dynamic range
                    cell_ID = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.CellID;
                    mol_ID = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.MolID;
                    app.movie_data.current_video = illustrateMol(app.movie_data, cell_ID, mol_ID, 0, app.SaveeveryviewedmoleculeCheckBox.Value, strcat('Cell', num2str(cell_ID), '_Mol', num2str(mol_ID)));
        
                    %display updated video at correct frame
                    imagesc(app.movie_data.current_video(:,:,app.movie_data.state.labeller_frame_video), 'parent', app.UIAxes_image_event_labeller);
                    axis(app.UIAxes_image_event_labeller, 'equal');
                    axis(app.UIAxes_image_event_labeller, 'off');
                    colormap(app.UIAxes_image_event_labeller, gray(256));
                    
                    app.textout.Value = "The upper limit of the dynamic range of illustrations in manual mode has been set to " + num2str(app.movie_data.params.dr_lo) + "." + newline + newline + ...
                        "The video has been updated accordingly.";
                otherwise
                
            end
        end

        % Value changed function: DynamicrangemodeDropDown
        function DynamicrangemodeDropDownValueChanged(app, event)
            app.movie_data.params.dr_auto = app.DynamicrangemodeDropDown.Value;
            app.textout.Value = strcat('The dynamic range of illustrations is running in', {' '}, app.movie_data.params.dr_auto, ' mode');
            
            %re-capture video from FITS stack using new dynamic range
            cell_ID = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.CellID;
            mol_ID = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.MolID;
            
            app.movie_data.current_video = illustrateMol(app.movie_data, cell_ID, mol_ID, 0, app.SaveeveryviewedmoleculeCheckBox.Value, strcat('Cell', num2str(cell_ID), '_Mol', num2str(mol_ID)));
            imagesc(app.movie_data.current_video(:,:,app.movie_data.state.labeller_frame_video), 'parent', app.UIAxes_image_event_labeller);
        end

        % Value changed function: IllustrationborderSpinner
        function IllustrationborderSpinnerValueChanged(app, event)
            app.movie_data.params.ill_border = app.IllustrationborderSpinner.Value;
            app.textout.Value = "The border size (in pixels) around each cell ROI for use in illustrations has been set to " + num2str(app.movie_data.params.ill_border);
        end

        % Value changed function: OverlayopacityEditField
        function OverlayopacityEditFieldValueChanged(app, event)
            app.movie_data.params.overlay_pc = app.OverlayopacityEditField.Value;
            app.textout.Value = "Brightfield overlay opacity for illustrations has been set to " + num2str(app.movie_data.params.overlay_pc) + "%";
        end

        % Selection change function: MainTabGroup
        function MainTabGroupSelectionChanged(app, event)
            selectedTab = app.MainTabGroup.SelectedTab;
            
            %code to run when user moves between tabs
            if strcmp(selectedTab.Title, 'Event labeller')
                % << for future use >>
            elseif strcmp(selectedTab.Title, 'Insights')
                % << for future use >>
            elseif strcmp(selectedTab.Title, 'ML classification')
                %repopulate the ML features list in the ML classificaiton tab
                if isprop(app, 'movie_data') && isfield(app.movie_data, 'params') && isfield(app.movie_data.params, 'column_titles')
                    a = app.MLfeatures.Children;
                    a.delete;
                    for ii = 1:(size(app.movie_data.params.column_titles.tracks,2) - 1)
                        features(ii) = uitreenode(app.MLfeatures);
                        features(ii).Text = string(app.movie_data.params.column_titles.tracks(ii));
                    end
                end
                
                if isprop(app, 'movie_data')
%                    setDefaultsAllModels(app); % << placeholder >>
                end
                
            end
            
        end

        % Value changed function: MinimumtracklengthSpinner
        function MinimumtracklengthSpinnerValueChanged(app, event)
            app.movie_data.params.min_track_len = app.MinimumtracklengthSpinner.Value;
            app.textout.Value = "Minimum track length changed to " + num2str(app.movie_data.params.min_track_len) + " frames";
        end

        % Button pushed function: LoadanalysisButton
        function LoadanalysisButtonPushed(app, event)
            %prompt user to select file and check they didn't press cancel
            [temp_name, temp_path] = uigetfile('*.mat');
            if isequal(temp_name, 0) || isequal(temp_path, 0)
                return;
            end
            
            %load the data, and check it is valid (initially just testing that app.movie_data exists)
            app.movie_data = load(fullfile(temp_path, temp_name));
            if isfield(app.movie_data, 'movie_data')
                app.movie_data = app.movie_data.movie_data;
            else
                app.movie_data = struct();
            end
            
            
            if isfield(app.movie_data.params,'title')
                app.CurrentlyloadeddatasetTextArea.Value = app.movie_data.params.title;
            end
            
            %repopulate current GUI component values from main struct back into GUI controls so the user is aware of their values
            %note that this is not an exhaustive list as backwards compatibility is currently required for earlier analysis files
            
            %prepare tab components
            app.PixelscaleEditField.Value       = app.movie_data.params.px_scale;               %pixel scale (nm/pixel)
            app.TrackbufferframesSpinner.Value  = app.movie_data.params.track_buffer;           %minimum number of empty frames between tracks in same cell
            app.IntensityboxpxSpinner.Value     = app.movie_data.params.I_border;               %size of border around localisation to obtain fluorescence intensity
            app.MinimumtracklengthSpinner.Value = app.movie_data.params.min_track_len;          %minimum length of a track (in localisations) to use in analysis
            app.MemoryparameterSpinner.Value    = app.movie_data.params.mem_param;              %memory parameter used to filter tracks
            app.FilteringmethodDropDown.Value   = app.movie_data.params.filtering_method;       %filtering method which determines how filterTracks() handles >1 track in cell at once
            app.FilteragainstDropDown.Value     = app.movie_data.params.filter_against;         %records whether to filter by comparing to other tracks or all localisations
            app.FiltertoleranceSpinner.Value    = app.movie_data.params.filter_tolerance;       %maximum number of localisations that can coincide with track before it is filtered in localisation mode
            
            %Video and trajectory illustration tab components
            app.DynamicrangemodeDropDown.Value  = app.movie_data.params.dr_auto;                %mode used to set dynamic range of fluor data in illustrations ('auto' or 'manual')
            app.DynamicrangelowEditField.Value  = app.movie_data.params.dr_lo;                  %lower limit of dynamic range of fluor data in 'manual' mode
            app.DynamicrangehighEditField.Value = app.movie_data.params.dr_hi;                  %upper limit of dynamic range of fluor data in 'manual' mode
            app.IllustrationborderSpinner.Value = app.movie_data.params.ill_border;             %size of border around cells in illustrations (pixels)
            app.OverlayopacityEditField.Value   = app.movie_data.params.overlay_pc;             %percentage intensity of brightfield overlay in illustrations
            app.VideoupscalefactorSpinner.Value = app.movie_data.params.video_upscaling_factor; %integer factor used to upscale exported videos
            app.ExportframerateEditField.Value  = app.movie_data.params.export_frame_rate;      %frame rate used to export videos
            
            %labelling controls
            app.PlayexportedvideoCheckBox.Value = app.movie_data.state.play_exported_video;     %play back exported video generated by the event labeller
            
            %event histograms tab
            app.BinwidthsSpinner.Value          = app.movie_data.params.hist_bin_wid;           %histogram bin width used by the Insights tab
            
            %Notes tab - specific test as this was not present in previous versions
            if isfield(app.movie_data, "notes")
                app.DataanalysisnotesTextArea.Value = app.movie_data.notes;                         %data analysis notes recorded by the user
            end
            
            %clear the existing event labelling buttons
            delete(app.Event_label_buttons.Children);
            
            %repopulate dropdown boxes with relevant states and options
            app.EventstoviewDropDown.Items  = app.movie_data.params.class_names;    %put class names back in class selection box in event histogram options
            app.EventoverlayerSOI.Items     = app.movie_data.params.class_names;    %put class names back in class selection box in event overlayer options
            
            %return focus to GUI
            figure(app.InVivoKineticsUIFigure)
        end

        % Button pushed function: OpenPDFmanualButton
        function OpenPDFmanualButtonPushed(app, event)
            app.textout.Value = "Opening PDF manual";
            open('splash_screen.pdf');
        end

        % Value changed function: VideoupscalefactorSpinner
        function VideoupscalefactorSpinnerValueChanged(app, event)
            app.movie_data.params.video_upscaling_factor = app.VideoupscalefactorSpinner.Value;
            app.textout.Value = "Upscaling factor for video illustrations has been set to " + num2str(app.movie_data.params.video_upscaling_factor);
        end

        % Value changed function: ExportframerateEditField
        function ExportframerateEditFieldValueChanged(app, event)
            app.movie_data.params.export_frame_rate = app.ExportframerateEditField.Value;
            app.textout.Value = "Export video frame rate has been set to " + num2str(app.movie_data.params.export_frame_rate);
        end

        % Value changed function: FilteringmethodDropDown
        function FilteringmethodDropDownValueChanged(app, event)
            app.movie_data.params.filtering_method = app.FilteringmethodDropDown.Value;
            app.textout.Value = "Filtering method has been set to " + num2str(app.movie_data.params.filtering_method);
        end

        % Value changed function: MemoryparameterSpinner
        function MemoryparameterSpinnerValueChanged(app, event)
            app.movie_data.params.mem_param = app.MemoryparameterSpinner.Value;
            app.textout.Value = "Memory parameter has been set to " + num2str(app.movie_data.params.mem_param);
        end

        % Button pushed function: GotostartButton
        function GotostartButtonPushed(app, event)
            %set the slider back to the start
            app.Slider_event_labeller.Value = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(1,app.movie_data.state.col_t);
            
            %put vertical line back to start
            line_obj        = findobj(app.UIAxes_event_labeller, 'Tag', 'current_pos');
            line_obj.Value  = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(1,app.movie_data.state.col_t);

            %put the video back to start
            imagesc(app.UIAxes_image_event_labeller, app.movie_data.current_video(:,:, 1));
            app.movie_data.state.labeller_frame_video = 1;
            
            %put the circle back to start
            current_loc = findobj(app.UIAxes_event_labeller, 'Tag', 'current_loc');
            current_loc.XData = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(1,app.movie_data.state.col_t);
            current_loc.YData = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(1,app.movie_data.state.col_feature);
        end

        % Button pushed function: GotoendButton
        function GotoendButtonPushed(app, event)
            app.Slider_event_labeller.Value = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(end,app.movie_data.state.col_t);
            temp.Value = app.Slider_event_labeller.Value;
            Slider_event_labellerValueChanging(app, temp);
        end

        % Value changing function: Slider_event_labeller
        function Slider_event_labellerValueChanging(app, event)
            %get the current value
            changing_value = event.Value;
            
            %update the vertical line
            line_obj        = findobj(app.UIAxes_event_labeller, 'Tag', 'current_pos');
            line_obj.Value  = changing_value;
            
            %highlight the next available timepoint
            [vid_idx, loc_idx, coords] = getNextAvailablePoint(changing_value,...
                [0 : (1/app.movie_data.params.frame_rate) : app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(end,app.movie_data.state.col_t)]',...
                [app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(:,app.movie_data.state.col_t) app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(:,app.movie_data.state.col_feature)]);
            app.movie_data.state.labeller_track_pos = loc_idx;
            
            %only update the video and next datapoint if it is new;
            %very important for performance on slower machines
            if app.movie_data.state.labeller_frame ~= vid_idx
                imagesc(app.UIAxes_image_event_labeller, app.movie_data.current_video(:,:, vid_idx));  %imagesc is much faster than imshow because it doesn't add a load of tools
                current_loc = findobj(app.UIAxes_event_labeller, 'Tag', 'current_loc');
                current_loc.XData = coords(1);
                current_loc.YData = coords(2);
                app.movie_data.state.labeller_frame_video = vid_idx;
            end
        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            %if data from a previous event labelling session already exists, the user needs to verify overwrite
            try
                if isfield(app.movie_data, 'results') && isfield(app.movie_data.results, 'VisuallyLabelled') &&...
                        ~isempty(app.movie_data.results.VisuallyLabelled.LabelledMols) && size(app.movie_data.results.VisuallyLabelled.LabelledMols, 1) > 0
                
                    answer = questdlg('This datafile contains data that has already been manually labelled, are you sure you want to overwrite this?',...
                                      'Warning: you are about to overwrite existing data', 'Yes, overwrite', 'Continue where I left off', 'Cancel', 'Cancel');
                    %handle response
                    switch answer
                        case 'Cancel'
                            return;
                        case 'Continue where I left off'
                            %populate buttons using class names stored in params substruct
                            regenerateLabelButtons(app);
                        case 'Yes, overwrite'
                            %start labelling from scratch
                            labelFromScratch(app);
                    end
                    
                else
                    %start labelling from scratch
                    labelFromScratch(app);
                end
                
                repopulateEventLabeller(app);
                
            catch
                app.textout.Value = "Error in labelFromScratch(): user did not provide labels, please begin labelling from scratch again.";
                figure(app.InVivoKineticsUIFigure);
                return;
            end
            
        end

        % Button pushed function: DisplaystatisticsButton
        function DisplaystatisticsButtonPushed(app, event)
            %find the class ID
            class_number = strcmp(app.movie_data.params.class_names, app.EventstoviewDropDown.Value);
            class_number = find(class_number);
            
            %compile the labelled states in the system
            extractAllStates(app);
            
            if isempty(app.movie_data.results.VisuallyLabelled.extractedStates{1, class_number})
                app.textout.Value = 'There is no data to show for this class';
                cla(app.UIAxes_compiled_events, 'reset');
                xlabel(app.UIAxes_compiled_events, 'Number of events');
                ylabel(app.UIAxes_compiled_events, 'Event duration (s)');
                title(app.UIAxes_compiled_events, 'Histogram of manually-labelled events');
                box(app.UIAxes_compiled_events, 'on');
                return
            end
            
            %compile the event histogram data
            data = gatherEventHistogramData(app, class_number);
            
            %transfer a copy to the results substruct of app handles for use elsewhere (e.g. CSV export)
            app.movie_data.results.current_histogram_data = data;
            
            if isempty(data)
                app.textout.Value = 'There is no data to display for these settings';
                cla(app.UIAxes_compiled_events, 'reset');
                xlabel(app.UIAxes_compiled_events, 'Number of events'); ylabel(app.UIAxes_compiled_events, 'Event duration (s)'); title(app.UIAxes_compiled_events, 'Histogram of manually-labelled events'); box(app.UIAxes_compiled_events, 'on');
            else
                %plot the histogram
                h = histogram(app.UIAxes_compiled_events, data(:,4), 'BinWidth', app.movie_data.params.hist_bin_wid);
                h.FaceColor = 'black';
                h.EdgeColor = 'white';
                h.LineWidth = 2;
                xlabel(app.UIAxes_compiled_events, 'Event duration (s)'); ylabel(app.UIAxes_compiled_events, 'Number of events');
                title(app.UIAxes_compiled_events, 'Histogram of manually-labelled events');
                box(app.UIAxes_compiled_events, 'on');
                app.UIAxes_compiled_events.YLim = [0 ceil(max(app.UIAxes_compiled_events.Children.Values)*1.1)];
            end
        end

        % Button pushed function: SaveillustrationButton
        function SaveillustrationButtonPushed(app, event)
            %save illlustration (and optionally data) as requested by user
            video_name = inputdlg('Please enter a name for the video', 'Saving video', [1 150], {strcat('[', char(app.CurrentlyloadeddatasetTextArea.Value), '] ')});
            if ~isempty(video_name)
                video_name = video_name{1};
                %save video
                illustrateMol(app.movie_data, app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.CellID,...
                    app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.MolID,...
                    app.PlayexportedvideoCheckBox.Value, 1, video_name);
                
                app.textout.Value = "Illustration has been saved for cell " + num2str(app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.CellID) +...
                    ", molecule " + num2str(app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.MolID) + ".";
                
                %save molecule data to same location
                if app.SavealldatawithvideoCheckBox.Value == 1
                    saveMolCSV(app, app.movie_data.state.event_labeller_current_ID, app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.CellID,...
                        app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.MolID, video_name);
                    
                    app.textout.Value = "Illustration and data have been saved for cell " + num2str(app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.CellID) +...
                        ", molecule " + num2str(app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.MolID) + ".";
                end
            else
                app.textout.Value = 'Illustration has not been saved at the request of the user';
            end
            
        end

        % Button pushed function: backwardButton
        function backwardButtonPushed(app, event)
            %if video frame is > 1, move one frame to the left
            if app.movie_data.state.labeller_frame_video > 1
                new_timepoint = (app.movie_data.state.labeller_frame_video - 2) * (1/app.movie_data.params.frame_rate);
                %super-hacky
                [~, loc_idx, coords] = getNextAvailablePoint((new_timepoint - 0.00001),...
                    [0 : (1/app.movie_data.params.frame_rate) : app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(end,app.movie_data.state.col_t)]',...
                    [app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(:,app.movie_data.state.col_t)...
                    app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(:,app.movie_data.state.col_feature)]);
                
                %move the vertical line
                line_obj = findobj(app.UIAxes_event_labeller, 'Tag', 'current_pos');
                line_obj.Value = new_timepoint;
                
                %move the slider position
                app.Slider_event_labeller.Value = new_timepoint;
                
                %update the video index, and display the previous video frame
                app.movie_data.state.labeller_frame_video = app.movie_data.state.labeller_frame_video - 1;
                imagesc(app.UIAxes_image_event_labeller, app.movie_data.current_video(:,:, app.movie_data.state.labeller_frame_video));
                
                %work out if the localisation needs to be updated, and if so update the red circle and localisation index
                app.movie_data.state.labeller_track_pos = loc_idx;
                current_loc_obj = findobj(app.UIAxes_event_labeller, 'Tag', 'current_loc');
                current_loc_obj.XData = coords(1);
                current_loc_obj.YData = coords(2);
            end
        end

        % Button pushed function: forwardButton
        function forwardButtonPushed(app, event)
            %if video frame is not the final frame, move one frame to the right
            if app.movie_data.state.labeller_frame_video < size(app.movie_data.current_video,3)
                new_timepoint = (app.movie_data.state.labeller_frame_video) * (1/app.movie_data.params.frame_rate);
                %super-hacky
                [~, loc_idx, coords] = getNextAvailablePoint((new_timepoint - 0.00001), [0 : (1/app.movie_data.params.frame_rate) : app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(end,app.movie_data.state.col_t)]', [app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(:,app.movie_data.state.col_t) app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID,1}.Mol(:,app.movie_data.state.col_feature)]);
                
                %move the vertical line
                line_obj = findobj(app.UIAxes_event_labeller, 'Tag', 'current_pos');
                line_obj.Value = new_timepoint;
                
                %move the slider position
                if new_timepoint > app.Slider_event_labeller.Limits(2)
                    app.Slider_event_labeller.Value = app.Slider_event_labeller.Limits(2);
                else
                    app.Slider_event_labeller.Value = new_timepoint;
                end
                
                %update the video index, and display the previous video frame
                app.movie_data.state.labeller_frame_video = app.movie_data.state.labeller_frame_video + 1;
                imagesc(app.UIAxes_image_event_labeller, app.movie_data.current_video(:,:, app.movie_data.state.labeller_frame_video));

                %work out if the localisation needs to be updated, and if so update the red circle and localisation index
                app.movie_data.state.labeller_track_pos = loc_idx;
                current_loc_obj = findobj(app.UIAxes_event_labeller, 'Tag', 'current_loc');
                current_loc_obj.XData = coords(1);
                current_loc_obj.YData = coords(2);
            end
        end

        % Value changed function: PlayexportedvideoCheckBox
        function PlayexportedvideoCheckBoxValueChanged(app, event)
            app.movie_data.state.play_exported_video = app.PlayexportedvideoCheckBox.Value;
            if app.PlayexportedvideoCheckBox.Value
                app.textout.Value = "Video illustrations will now play after saving";
            else
                app.textout.Value = "Video illustrations will no longer play after saving";
            end
        end

        % Value changed function: BinwidthsSpinner
        function BinwidthsSpinnerValueChanged(app, event)
            app.movie_data.params.hist_bin_wid = app.BinwidthsSpinner.Value;
            app.textout.Value = "Histogram bin width has been set to " + num2str(app.BinwidthsSpinner.Value);
        end

        % Button pushed function: DiscardmoleculeButton
        function DiscardmoleculeButtonPushed(app, event)
            %get IDs of cell to be deleted (this cannot be moved inside textout command below as the IDs would have been deleted)
            del_cell = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.CellID;
            del_mol = app.movie_data.results.VisuallyLabelled.LabelledMols{app.movie_data.state.event_labeller_current_ID, 1}.MolID;
            
            %delete the current entry from the next, and load the next
            app.movie_data.results.VisuallyLabelled.LabelledMols(app.movie_data.state.event_labeller_current_ID,:) = [];
            
            %notify user of deleted molecule
            app.textout.Value = "Molecule " + num2str(del_mol) + " in cell " + num2str(del_cell) + " was discarded";
            repopulateEventLabeller(app);
        end

        % Button pushed function: ComputeDButton
        function ComputeDButtonPushed(app, event)
            app.textout.Value = "Computing MSDs for all labelled molecules";
            compileMSDsAllLabelled(app);
        end

        % Button pushed function: ExporthistogramButton
        function ExporthistogramButtonPushed(app, event)
            %obtain a suitable filename
            hist_name = string(datetime);
            hist_name = erase(hist_name,':');
            hist_name = strcat(hist_name, '_choose a file name.csv');
            
            %prompt user to modify it; then ensure they've not deleted extension
            hist_name = inputdlg('Please enter a name for the exported file', 'Export current histogram data', [1 35], hist_name);
            hist_name = hist_name{1};
            if ~strcmp('.csv', hist_name(end-3:end))
                hist_name = strcat(hist_name, '.csv');
            end
            
            if ~exist(fullfile(app.movie_data.params.ffPath, 'saved histogram data'), 'dir')
                mkdir(fullfile(app.movie_data.params.ffPath, 'saved histogram data'));
            end
            
            %write histogram data
            export_format = app.HistogramdatatoexportDropDown.Value;
            switch export_format
                case 'Raw data'
                    %write the underlying data used to generate the histogram
                    T = array2table([app.UIAxes_compiled_events.Children.Data]);
                    T.Properties.VariableNames = {'Event duration (s)'};
                    writetable(T, fullfile(app.movie_data.params.ffPath, 'saved histogram data', hist_name));
                    app.textout.Value = "The data used to generate the histogram has been exported to a CSV file.";
                case 'Only bins shown'
                    %write the data currently displayed in the histogram
                    T = array2table([app.UIAxes_compiled_events.Children.BinEdges(1,2:end)' app.UIAxes_compiled_events.Children.Values']);
                    T.Properties.VariableNames = {'Bin centre', 'Counts'};
                    writetable(T, fullfile(app.movie_data.params.ffPath, 'saved histogram data', hist_name));
                    app.textout.Value = "The bin centres and occupancies for the current histogram has been exported to a CSV file.";
                otherwise
                    error("IVK:ExporthistogramButtonPushed:UnknownExportFormat");
            end
            
        end

        % Button pushed function: UndolabelButton
        function UndolabelButtonPushed(app, event)
            repopulateEventLabeller(app);
        end

        % Key press function: InVivoKineticsUIFigure
        function InVivoKineticsUIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'f'
                    forwardButtonPushed(app, event);
                case 'd'
                    backwardButtonPushed(app, event);
                case 's'
                    GotostartButtonPushed(app, event);
                case 'e'
                    GotoendButtonPushed(app, event);
            end
        end

        % Button pushed function: GeneratetrainingsetButton
        function GeneratetrainingsetButtonPushed(app, event)
            %wipe previous feature scaling variables
            app.movie_data.models.temp_params = [];
            
            %obtain the inputs for shuffling, and splitting, the training data
            app.movie_data.models.temp_params.data_split = str2double(strsplit(app.TrainingvalidationtestsplitEditField.Value, ','));
            
            %check the data split adds to one
            if sum(app.movie_data.models.temp_params.data_split) ~= 1
                msgbox('The sum of the data split between training, validation, and test data is not currently 1. Please adjust the split to sum to 1 and try again.', 'Error', 'warn');
                app.textout.Value = "You have entered an incorrect split between traning, validation, and test data. Please update these values and try again.";
                return;
            end
            
            %perform feature scaling on labelled data; produces a new cell array FeatureScaledData inside the results substruct, which ignores unlabelled molecules, and crops trajectories according to selected feature types
            scaleLabelledFeatures(app);
            
            %erase any previous training data; handles cases where the user may train a model with validation data, followed by without, the previous validation dataset would persist
            [app.movie_data.results.labelled_data, app.movie_data.results.train_data, app.movie_data.results.val_data, app.movie_data.results.test_data,...
                app.movie_data.results.ref_labelled_data, app.movie_data.results.ref_train_data, app.movie_data.results.ref_val_data, app.movie_data.results.ref_test_data] = deal([]);
            
            %keep the column titles with the data
            app.movie_data.results.all_feature_names = app.movie_data.params.column_titles.tracks;
            
            %shuffle the scaled data
            shuffleScaledData(app);
            
            %split the data into training, validation, and test datasets
            splitData(app);
            
            %reformat the training data
            switch app.SpantypeDropDown.Value
                case "Whole trajectories"
                    app.movie_data.results.padding = true;
                case "Segments"
                    app.textout.Value = "Splitting training data into discrete segments";
                    reformatToSpans(app);
                    app.movie_data.results.padding = false;
                case "Sliding window"
                    app.textout.Value = "Processing training data with a sliding window";
                    reformatToSpans(app);
                    app.movie_data.results.padding = false;
                otherwise
                    
            end

            app.textout.Value = "Generated training set";
        end

        % Button pushed function: TrainMLmodelButton
        function TrainMLmodelButtonPushed(app, event)
            %check the training data exists
            if ~isprop(app, 'movie_data') || ~isfield(app.movie_data, 'results') || ~isfield(app.movie_data.results, 'train_data') || ~size(app.movie_data.results.train_data, 1) > 0
                warndlg("No training data is currently loaded, please load the data before continuing","Warning");
                return;
            end
            
            %train ML model based on user model type selection
            model_type = app.ModeltypeDropDown.Value;
            switch model_type
                %much of the code in this switch statement is repetitive
                %and will be simplified in a future code refactoring
                case "Random forest"
                    app.textout.Value = "Training random forest model";
                    
                    if ~isfield(app.movie_data.models.temp_params, "N_trees")
                        app.movie_data.models.temp_params.N_trees = app.movie_data.models.RF.N_trees;
                    end
                    
                    %store metadata and variables used for feature scaling and training with model
                    app.movie_data.models.RF                = app.movie_data.models.temp_params;
                    app.movie_data.models.RF.model_type     = "Random forest";
                    app.movie_data.models.RF.class_names    = app.movie_data.params.class_names;
                    app.movie_data.models.RF.feature_names  = app.movie_data.params.column_titles.tracks([app.movie_data.models.temp_params.feature_cols]);
                    app.movie_data.models.RF.user           = app.UserEditField.Value;
                    app.movie_data.models.RF.timestamp      = string(datetime);
                    app.movie_data.models.current_model     = model_type;
                    
                    %before the new model is trained wipe the metrics of any previous model of this type
                    app.movie_data.models.RF.metrics = [];
                    
                    tic
                    %train the model; future versions likely move this substruct/cell array app.movie_data.models{} to allow multiple models to be loaded simultaneously
                    app.movie_data.models.RF.model = trainRF(app.movie_data.results.train_data, app.movie_data.models.temp_params);
                    t = toc;
                    app.textout.Value = "Completed training of random forest model in " + num2str(t) + " seconds";
                    
                    %run on test data to evaluate performance
                    X_test = app.movie_data.results.test_data(:, 1:end-1);
                    Y_test = app.movie_data.results.test_data(:, end);
                    [Y_pred, ~] = predict(app.movie_data.models.RF.model, X_test);
                    Y_pred = str2double(Y_pred);
                    
                    accuracy = sum(Y_pred == Y_test) / numel(Y_test);
                    
                    C = confusionmat(Y_test, Y_pred);
                    confusionchart(C, app.movie_data.models.RF.class_names, 'FontSize', 18);
                    
                    %initialise vectors for precision, recall, and F1 score
                    [precision, recall, f1_score] = deal(zeros(size(app.movie_data.models.RF.class_names, 1), 1));
                    
                    %loop over classes computing precision, recall and F1 score
                    for ii = 1:size(app.movie_data.models.RF.class_names, 1)
                        true_positives  = C(ii, ii);
                        false_positives = sum(C(:, ii)) - true_positives;
                        false_negatives = sum(C(ii, :)) - true_positives;
                        
                        precision(ii)   = true_positives / (true_positives + false_positives);
                        recall(ii)      = true_positives / (true_positives + false_negatives);
                        f1_score(ii)    = 2*(precision(ii)*recall(ii)) / (precision(ii) + recall(ii));
                    end
                    
                    %average over all classes
                    avg_precision   = mean(precision, 'omitnan');
                    avg_recall      = mean(recall, 'omitnan');
                    avg_f1_score    = mean(f1_score, 'omitnan');
                    
                    %display model performance metrics to user
                    app.textout.Value = "Metrics from hold out test data"  + newline +...
                        "Accuracy: " + num2str(accuracy, "%.4f") + newline +...
                        "Precision: " + num2str(avg_precision, "%.4f") + newline +...
                        "Recall: " + num2str(avg_recall, "%.4f") + newline +...
                        "F1 score: " + num2str(avg_f1_score, "%.4f");

                    %store the metrics
                    app.movie_data.models.RF.metrics.accuracy   = accuracy;
                    app.movie_data.models.RF.metrics.precision  = avg_precision;
                    app.movie_data.models.RF.metrics.recall     = avg_recall;
                    app.movie_data.models.RF.metrics.recall     = avg_f1_score;
                    
                case "Gradient boosted trees"
                    app.textout.Value = "Training gradient boosted trees model";

                    % Check and store parameters
                    if ~isfield(app.movie_data.models.temp_params, "N_trees")
                        app.movie_data.models.temp_params.N_trees = app.movie_data.models.GB.N_trees;
                    end
                    
                    % Store metadata and variables used for feature scaling and training with model
                    app.movie_data.models.GB = app.movie_data.models.temp_params;
                    app.movie_data.models.GB.model_type = "Gradient boosted trees";
                    app.movie_data.models.GB.class_names = app.movie_data.params.class_names;
                    app.movie_data.models.GB.feature_names = app.movie_data.params.column_titles.tracks([app.movie_data.models.temp_params.feature_cols]);
                    app.movie_data.models.GB.user = app.UserEditField.Value;
                    app.movie_data.models.GB.timestamp = string(datetime);
                    app.movie_data.models.current_model = model_type;
                    
                    % Reset metrics of any previous model of this type
                    app.movie_data.models.GB.metrics = [];
                    
                    % Train the model
                    tic
                    app.movie_data.models.GB.model = trainGB(app.movie_data.results.train_data, app.movie_data.models.temp_params);
                    t = toc;
                    app.textout.Value = "Completed training of gradient boosted trees model in " + num2str(t) + " seconds";
                    
                    %run on test data to evaluate performance
                    X_test = app.movie_data.results.test_data(:, 1:end-1);
                    Y_test = app.movie_data.results.test_data(:, end);
                    [Y_pred, ~] = predict(app.movie_data.models.GB.model, X_test);
                    
                    accuracy = sum(Y_pred == Y_test) / numel(Y_test);
                    
                    C = confusionmat(Y_test, Y_pred);
                    confusionchart(C, app.movie_data.models.GB.class_names, 'FontSize', 18);
                    
                    %initialise vectors for precision, recall, and F1 score
                    [precision, recall, f1_score] = deal(zeros(size(app.movie_data.models.GB.class_names, 1), 1));
                    
                    %loop over classes computing precision, recall and F1 score
                    for ii = 1:size(app.movie_data.models.GB.class_names, 1)
                        true_positives  = C(ii, ii);
                        false_positives = sum(C(:, ii)) - true_positives;
                        false_negatives = sum(C(ii, :)) - true_positives;
                        
                        precision(ii)   = true_positives / (true_positives + false_positives);
                        recall(ii)      = true_positives / (true_positives + false_negatives);
                        f1_score(ii)    = 2*(precision(ii)*recall(ii)) / (precision(ii) + recall(ii));
                    end
                    
                    %average over all classes
                    avg_precision   = mean(precision, 'omitnan');
                    avg_recall      = mean(recall, 'omitnan');
                    avg_f1_score    = mean(f1_score, 'omitnan');
                    
                    %display model performance metrics to user
                    app.textout.Value = "Metrics from hold out test data"  + newline +...
                        "Accuracy: " + num2str(accuracy*100, "%.2f") + "%" + newline +...
                        "Precision: " + num2str(avg_precision, "%.2f") + newline +...
                        "Recall: " + num2str(avg_recall, "%.2f") + newline +...
                        "F1 score: " + num2str(avg_f1_score, "%.2f");
                    
                    %store the metrics
                    app.movie_data.models.GB.metrics.accuracy   = accuracy;
                    app.movie_data.models.GB.metrics.precision  = avg_precision;
                    app.movie_data.models.GB.metrics.recall     = avg_recall;
                    app.movie_data.models.GB.metrics.recall     = avg_f1_score;

                case "Gated Recurrent Unit (GRU)"
                    app.textout.Value = 'Training GRU model';
                    
                    %copy over the temporary parameters
                    app.movie_data.models.GRU = app.movie_data.models.temp_params;

                    %store metadata and variables used for feature scaling and training with model
                    app.movie_data.models.GRU.model_type = "Gated Recurrent Unit";
                    app.movie_data.models.GRU.class_names = app.movie_data.params.class_names;
                    app.movie_data.models.GRU.feature_names = app.movie_data.params.column_titles.tracks([app.movie_data.models.temp_params.feature_cols]);
                    app.movie_data.models.GRU.user = app.UserEditField.Value;
                    app.movie_data.models.GRU.timestamp = string(datetime);
                    app.movie_data.models.current_model = "Gated Recurrent Unit";
                    app.movie_data.models.GRU.max_len = size(app.movie_data.results.train_data{1,1}, 2);
                    
                    if app.movie_data.results.padding == true
                        %number of features is N+1 as an additional feature is added for the padded mask
                        N_features  = size(app.movie_data.models.GRU.feature_cols,2) + 1;
                        %number of classes is N+1 as an additional class is included for padded region of trajectory
                        N_classes   = size(app.movie_data.params.class_names,1) + 1;
                        %keep track of whether model was trained on windowed data
                        app.movie_data.models.GRU.windowed = false;
                    else
                        N_features  = size(app.movie_data.models.GRU.feature_cols,2);
                        N_classes   = size(app.movie_data.params.class_names,1);
                        app.movie_data.models.GRU.windowed = true;
                    end
                    
                    %define GRU network architecture, and configure options
                    layers = [sequenceInputLayer(N_features)
                        gruLayer(app.movie_data.models.GRU.N_units, 'OutputMode', 'sequence', ...
                            'InputWeightsL2Factor', app.movie_data.models.GRU.input_l2_factor, ...
                            'RecurrentWeightsL2Factor', app.movie_data.models.GRU.recurrent_l2_factor, ...
                            'BiasL2Factor', app.movie_data.models.GRU.bias_l2_factor)
                        dropoutLayer(app.movie_data.models.GRU.dropout_rate)
                        fullyConnectedLayer(N_classes)
                        softmaxLayer
                        classificationLayer];
                    
                    options = trainingOptions('adam', ...
                        'MaxEpochs', app.movie_data.models.GRU.max_epochs, ...
                        'MiniBatchSize', app.movie_data.models.GRU.batch_size, ...
                        'InitialLearnRate', app.movie_data.models.GRU.learn_rate, ...
                        'Verbose', 0, ...
                        'Plots', 'training-progress');

                    %train GRU network
                    app.movie_data.models.GRU.model = trainNetwork(app.movie_data.results.train_data, app.movie_data.results.train_labels, layers, options);
                    
                    %compute metrics
                    if app.movie_data.results.padding == true
                        [accuracy, precision, recall, f1_score, ~] = computeMetricsPadded(app.movie_data.models.GRU.model, app.movie_data.results.test_data, app.movie_data.results.test_labels);
                        app.textout.Value = "Accuracy: " + num2str(accuracy) + newline + "Precision: " + num2str(precision) + newline + "Recall: " + num2str(recall) + newline + "F1 Score: " + num2str(f1_score) + newline + newline + "Metrics were performed on non-padded regions of the dataset";
                    else
                        [accuracy, precision, recall, f1_score, ~] = computeMetrics(app.movie_data.models.GRU.model, app.movie_data.results.test_data, app.movie_data.results.test_labels);
                        app.textout.Value = "Accuracy: " + num2str(accuracy) + newline + "Precision: " + num2str(precision) + newline + "Recall: " + num2str(recall) + newline + "F1 Score: " + num2str(f1_score);
                    end
                    
                case "Long Short-Term Memory (LSTM)"
                    app.textout.Value = 'Training LSTM model';
                    
                    %copy over the temporary parameters
                    app.movie_data.models.LSTM = app.movie_data.models.temp_params;

                    %store metadata and variables used for feature scaling and training with model
                    app.movie_data.models.LSTM.model_type = "Long Short-Term Memory";
                    app.movie_data.models.LSTM.class_names = app.movie_data.params.class_names;
                    app.movie_data.models.LSTM.feature_names = app.movie_data.params.column_titles.tracks([app.movie_data.models.temp_params.feature_cols]);
                    app.movie_data.models.LSTM.user = app.UserEditField.Value;
                    app.movie_data.models.LSTM.timestamp = string(datetime);
                    app.movie_data.models.current_model = "Long Short-Term Memory";
                    app.movie_data.models.LSTM.max_len = size(app.movie_data.results.train_data{1,1}, 2);

                    if app.movie_data.results.padding == true
                        %number of features is N+1 as an additional feature is added for the padded mask
                        N_features  = size(app.movie_data.models.LSTM.feature_cols,2) + 1;
                        %number of classes is N+1 as an additional class is included for padded region of trajectory
                        N_classes   = size(app.movie_data.params.class_names,1) + 1;
                        %keep track of whether model was trained on windowed data
                        app.movie_data.models.LSTM.windowed = false;
                    else
                        N_features  = size(app.movie_data.models.LSTM.feature_cols,2);
                        N_classes   = size(app.movie_data.params.class_names,1);
                        app.movie_data.models.LSTM.windowed = true;
                    end
                    
                    
                    %define LSTM network architecture, and configure options
                    layers = [sequenceInputLayer(N_features)
                        lstmLayer(app.movie_data.models.LSTM.N_units, 'OutputMode', 'sequence', ...
                            'InputWeightsL2Factor', app.movie_data.models.LSTM.input_l2_factor, ...
                            'RecurrentWeightsL2Factor', app.movie_data.models.LSTM.recurrent_l2_factor, ...
                            'BiasL2Factor', app.movie_data.models.LSTM.bias_l2_factor)
                        dropoutLayer(app.movie_data.models.LSTM.dropout_rate)
                        fullyConnectedLayer(N_classes)
                        softmaxLayer
                        classificationLayer];
                    
                    options = trainingOptions('adam', ...
                        'MaxEpochs', app.movie_data.models.LSTM.max_epochs, ...
                        'MiniBatchSize', app.movie_data.models.LSTM.batch_size, ...
                        'InitialLearnRate', app.movie_data.models.LSTM.learn_rate, ...
                        'Verbose', 0, ...
                        'Plots', 'training-progress');
                                        
                    %train LSTM network
                    app.movie_data.models.LSTM.model = trainNetwork(app.movie_data.results.train_data, app.movie_data.results.train_labels, layers, options);
                    
                    %compute metrics
                    if app.movie_data.results.padding == true
                        [accuracy, precision, recall, f1_score, ~] = computeMetricsPadded(app.movie_data.models.LSTM.model, app.movie_data.results.test_data, app.movie_data.results.test_labels);
                        app.textout.Value = "Accuracy: " + num2str(accuracy) + newline + "Precision: " + num2str(precision) + newline + "Recall: " + num2str(recall) + newline + "F1 Score: " + num2str(f1_score) + newline + newline + "Metrics were performed on non-padded regions of the dataset";
                    else
                        [accuracy, precision, recall, f1_score, ~] = computeMetrics(app.movie_data.models.LSTM.model, app.movie_data.results.test_data, app.movie_data.results.test_labels);
                        app.textout.Value = "Accuracy: " + num2str(accuracy) + newline + "Precision: " + num2str(precision) + newline + "Recall: " + num2str(recall) + newline + "F1 Score: " + num2str(f1_score);
                    end
                    
                case "Bidirectional LSTM (BiLSTM)"
                    app.textout.Value = 'Training bidirectional LSTM model';
                    
                    %copy over the temporary parameters
                    app.movie_data.models.BiLSTM = app.movie_data.models.temp_params;

                    %store metadata and variables used for feature scaling and training with model
                    app.movie_data.models.BiLSTM.model_type = "Bidirectional Long Short-Term Memory";
                    app.movie_data.models.BiLSTM.class_names = app.movie_data.params.class_names;
                    app.movie_data.models.BiLSTM.feature_names = app.movie_data.params.column_titles.tracks([app.movie_data.models.temp_params.feature_cols]);
                    app.movie_data.models.BiLSTM.user = app.UserEditField.Value;
                    app.movie_data.models.BiLSTM.timestamp = string(datetime);
                    app.movie_data.models.current_model = "Bidirectional Long Short-Term Memory";
                    app.movie_data.models.BiLSTM.max_len = size(app.movie_data.results.train_data{1,1}, 2);

                    if app.movie_data.results.padding == true
                        %number of features is N+1 as an additional feature is added for the padded mask
                        N_features  = size(app.movie_data.models.BiLSTM.feature_cols,2) + 1;
                        %number of classes is N+1 as an additional class is included for padded region of trajectory
                        N_classes   = size(app.movie_data.params.class_names,1) + 1;
                        %keep track of whether model was trained on windowed data
                        app.movie_data.models.BiLSTM.windowed = false;
                    else
                        N_features  = size(app.movie_data.models.BiLSTM.feature_cols,2);
                        N_classes   = size(app.movie_data.params.class_names,1);
                        app.movie_data.models.BiLSTM.windowed = true;
                    end
                    
                    
                    %define BiLSTM network architecture, and configure options
                    layers = [sequenceInputLayer(N_features)
                        bilstmLayer(app.movie_data.models.BiLSTM.N_units, 'OutputMode', 'sequence', ...
                            'InputWeightsL2Factor', app.movie_data.models.BiLSTM.input_l2_factor, ...
                            'RecurrentWeightsL2Factor', app.movie_data.models.BiLSTM.recurrent_l2_factor, ...
                            'BiasL2Factor', app.movie_data.models.BiLSTM.bias_l2_factor)
                        dropoutLayer(app.movie_data.models.BiLSTM.dropout_rate)
                        fullyConnectedLayer(N_classes)
                        softmaxLayer
                        classificationLayer];
                    
                    options = trainingOptions('adam', ...
                        'MaxEpochs', app.movie_data.models.BiLSTM.max_epochs, ...
                        'MiniBatchSize', app.movie_data.models.BiLSTM.batch_size, ...
                        'InitialLearnRate', app.movie_data.models.BiLSTM.learn_rate, ...
                        'Verbose', 0, ...
                        'Plots', 'training-progress');
                                        
                    %train BiLSTM network
                    app.movie_data.models.BiLSTM.model = trainNetwork(app.movie_data.results.train_data, app.movie_data.results.train_labels, layers, options);
                    
                    %compute metrics
                    if app.movie_data.results.padding == true
                        [accuracy, precision, recall, f1_score, ~] = computeMetricsPadded(app.movie_data.models.BiLSTM.model, app.movie_data.results.test_data, app.movie_data.results.test_labels);
                        app.textout.Value = "Accuracy: " + num2str(accuracy) + newline + "Precision: " + num2str(precision) + newline + "Recall: " + num2str(recall) + newline + "F1 Score: " + num2str(f1_score) + newline + newline + "Metrics were performed on non-padded regions of the dataset";
                    else
                        [accuracy, precision, recall, f1_score, ~] = computeMetrics(app.movie_data.models.BiLSTM.model, app.movie_data.results.test_data, app.movie_data.results.test_labels);
                        app.textout.Value = "Accuracy: " + num2str(accuracy) + newline + "Precision: " + num2str(precision) + newline + "Recall: " + num2str(recall) + newline + "F1 Score: " + num2str(f1_score);
                    end

                case "Temporal Convolutional Network (TCN)"
                    

                otherwise
                    return
            end
        end

        % Selection change function: MLControlTabs
        function MLControlTabsSelectionChanged(app, event)
            if isprop(app, 'movie_data') && isfield(app.movie_data, 'params')
                selected_tab = app.MLControlTabs.SelectedTab.Title;
    
                %code to run when user moves between tabs
                switch selected_tab
                    case "Generate training data"
                        
                    case "Train models"
                        
                    case "Visualisation"
                        
                        if isfield(app.movie_data, "results") && isfield(app.movie_data, "models") && isfield(app.movie_data.models, "current_model")
                        
                            if strcmp(app.movie_data.models.current_model, "Random forest")
                                app.XaxisDropDown.Items = app.movie_data.models.RF.feature_names;
                                app.XaxisDropDown.Value = app.movie_data.models.RF.feature_names(1);
                                app.YaxisDropDown.Items = app.movie_data.models.RF.feature_names;
                                app.YaxisDropDown.Value = app.movie_data.models.RF.feature_names(2);
                                app.ZaxisDropDown.Items = app.movie_data.models.RF.feature_names;
                                
                                %add 'Do not display' option to handle 2D case
                                app.ZaxisDropDown.Items = cat(2, app.movie_data.models.RF.feature_names, "Do not display");
                                app.ZaxisDropDown.Value = app.movie_data.models.RF.feature_names(3);
                                
                            elseif strcmp(app.movie_data.models.current_model, "Gated Recurrent Unit")
                                app.XaxisDropDown.Items = app.movie_data.models.GRU.feature_names;
                                app.XaxisDropDown.Value = app.movie_data.models.GRU.feature_names(1);
                                app.YaxisDropDown.Items = app.movie_data.models.GRU.feature_names;
                                app.YaxisDropDown.Value = app.movie_data.models.GRU.feature_names(2);
                                app.ZaxisDropDown.Items = app.movie_data.models.GRU.feature_names;
                                
                                %add 'Do not display' option to handle 2D case
                                app.ZaxisDropDown.Items = cat(2, app.movie_data.models.GRU.feature_names, "Do not display");
                                app.ZaxisDropDown.Value = app.movie_data.models.GRU.feature_names(3);

                            elseif strcmp(app.movie_data.models.current_model, "Long Short-Term Memory")
                                app.XaxisDropDown.Items = app.movie_data.models.LSTM.feature_names;
                                app.XaxisDropDown.Value = app.movie_data.models.LSTM.feature_names(1);
                                app.YaxisDropDown.Items = app.movie_data.models.LSTM.feature_names;
                                app.YaxisDropDown.Value = app.movie_data.models.LSTM.feature_names(2);
                                app.ZaxisDropDown.Items = app.movie_data.models.LSTM.feature_names;
                                
                                %add 'Do not display' option to handle 2D case
                                app.ZaxisDropDown.Items = cat(2, app.movie_data.models.LSTM.feature_names, "Do not display");
                                app.ZaxisDropDown.Value = app.movie_data.models.LSTM.feature_names(3);

                            elseif strcmp(app.movie_data.models.current_model, "Bidirectional Long Short-Term Memory")
                                app.XaxisDropDown.Items = app.movie_data.models.BiLSTM.feature_names;
                                app.XaxisDropDown.Value = app.movie_data.models.BiLSTM.feature_names(1);
                                app.YaxisDropDown.Items = app.movie_data.models.BiLSTM.feature_names;
                                app.YaxisDropDown.Value = app.movie_data.models.BiLSTM.feature_names(2);
                                app.ZaxisDropDown.Items = app.movie_data.models.BiLSTM.feature_names;
                                
                                %add 'Do not display' option to handle 2D case
                                app.ZaxisDropDown.Items = cat(2, app.movie_data.models.BiLSTM.feature_names, "Do not display");
                                app.ZaxisDropDown.Value = app.movie_data.models.BiLSTM.feature_names(3);
                                
                            end
                        end
                    
                    otherwise
                        
                end
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            %get the features currently selected in the dropdown menus
            if ~strcmp(app.ZaxisDropDown.Value, "Do not display")
                %3D plot
                features_to_plot_idx = [find(strcmp(app.movie_data.params.column_titles.tracks, app.XaxisDropDown.Value)),...
                                    find(strcmp(app.movie_data.params.column_titles.tracks, app.YaxisDropDown.Value)),...
                                    find(strcmp(app.movie_data.params.column_titles.tracks, app.ZaxisDropDown.Value))];
                features_to_plot_names = [{app.XaxisDropDown.Value}, {app.YaxisDropDown.Value}, {app.ZaxisDropDown.Value}];
            else
                %2D plot
                features_to_plot_idx = [find(strcmp(app.movie_data.params.column_titles.tracks, app.XaxisDropDown.Value)),...
                                    find(strcmp(app.movie_data.params.column_titles.tracks, app.YaxisDropDown.Value))];
                features_to_plot_names = [{app.XaxisDropDown.Value}, {app.YaxisDropDown.Value}];
            end
            
            %compile an array of handles for reading the UIAxes limits
            h_array = {app.MLDataView, app.XLimLowSpinner, app.XLimHighSpinner,...
                                       app.YLimLowSpinner, app.YLimHighSpinner,...
                                       app.ZLimLowSpinner, app.ZLimHighSpinner};
            
            %clarify the state of the MLDataView UIAxes component
            if ~isfield(app.movie_data.state, "data_viewer_content")
                app.movie_data.state.data_viewer_content = "Visualisation";
            end

            switch app.DatatodisplayDropDown.Value
                case 'All user-classified data'
                    updateMLPlot(app.MLDataView, app.movie_data.results.ref_labelled_data, features_to_plot_idx,...
                        features_to_plot_names,...
                        app.movie_data.params.class_names, app.movie_data.params.event_label_colours, 'All user classified data', app.MaxpointstodisplaySpinner.Value, app.movie_data.state.data_viewer_content);
                    
                    %read the limits in the axes, and update in GUI
                    readLims(h_array);
                    
                case 'Training data'
                    updateMLPlot(app.MLDataView, app.movie_data.results.ref_train_data, features_to_plot_idx,...
                        features_to_plot_names,...
                        app.movie_data.params.class_names, app.movie_data.params.event_label_colours, 'Training data labelled by user', app.MaxpointstodisplaySpinner.Value, app.movie_data.state.data_viewer_content);
                    
                    %read the limits in the axes, and update in GUI
                    readLims(h_array);

                case 'Validation data'
                    updateMLPlot(app.MLDataView, app.movie_data.results.ref_val_data, features_to_plot_idx,...
                        features_to_plot_names,...
                        app.movie_data.params.class_names, app.movie_data.params.event_label_colours, 'Validation data labelled by user', app.MaxpointstodisplaySpinner.Value, app.movie_data.state.data_viewer_content);
                    
                    %read the limits in the axes, and update in GUI
                    readLims(h_array);

                case 'Test data'
                    updateMLPlot(app.MLDataView, app.movie_data.results.ref_test_data, features_to_plot_idx,...
                    features_to_plot_names,...
                    app.movie_data.params.class_names, app.movie_data.params.event_label_colours, 'Test data labelled by user', app.MaxpointstodisplaySpinner.Value, app.movie_data.state.data_viewer_content);
                    
                    %read the limits in the axes, and update in GUI
                    readLims(h_array);

                case 'ML classified data'
                    cla(app.MLDataView);
            end
            
            app.movie_data.state.data_viewer_content = 'Visualisation';

        end

        % Button pushed function: PanButton
        function PanButtonPushed(app, event)
            zoom(app.MLDataView, 'off');
            rotate3d(app.MLDataView, 'off');
            pan(app.MLDataView, 'on');
        end

        % Button pushed function: ZoomButton
        function ZoomButtonPushed(app, event)
            rotate3d(app.MLDataView, 'off');
            pan(app.MLDataView, 'off');
            zoom(app.MLDataView, 'on');
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            pan(app.MLDataView, 'off');
            zoom(app.MLDataView, 'off');
            rotate3d(app.MLDataView, 'on');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %initialise limits
            min_x = Inf; max_x = -Inf;
            min_y = Inf; max_y = -Inf;
            min_z = Inf; max_z = -Inf;
            
            %extract plots currently held by the ML data viewer
            children = app.MLDataView.Children;
            
            for ii = 1:length(children)
                %check it's a scsatter plot, and whether it exceeds current limits
                if isa(children(ii), 'matlab.graphics.chart.primitive.Scatter')
                    min_x = min(min_x, min(children(ii).XData));
                    max_x = max(max_x, max(children(ii).XData));
                    min_y = min(min_y, min(children(ii).YData));
                    max_y = max(max_y, max(children(ii).YData));
                    min_z = min(min_z, min(children(ii).ZData));
                    max_z = max(max_z, max(children(ii).ZData));
                end
            end
            
            %reset the axes limits
            xlim(app.MLDataView, [min_x, max_x]);
            ylim(app.MLDataView, [min_y, max_y]);
            
            %reset values in GUI axis limit controls
            app.XLimLowSpinner.Value    = min_x;
            app.XLimHighSpinner.Value   = max_x;
            app.YLimLowSpinner.Value    = min_y;
            app.YLimHighSpinner.Value   = max_y;
            
            %zlim contains no data for 2D plots, if so use zlim([0 1])
            if min_z < Inf
                %reset the z-axis limits
                zlim(app.MLDataView, [min_z, max_z]);
                
                %reset values in GUI axis limit controls
                app.YLimLowSpinner.Value    = min_y;
                app.YLimHighSpinner.Value   = max_y;

                %reset the angle of the 3D viewport
                view(app.MLDataView, [45 30]);
                zoom(app.MLDataView, 'reset');
            else
                %set z-axis limits as 0 to 1
                zlim(app.MLDataView, [0, 1]);
                
                %set values in GUI z-axis limit controls as 0 to 1
                app.ZLimLowSpinner.Value    = 0;
                app.ZLimHighSpinner.Value   = 1;

                %change view to X-Y plane
                view(app.MLDataView, [0 90]);
            end
            
        end

        % Button pushed function: XYButton
        function XYButtonPushed(app, event)
            view(app.MLDataView, [0 90]);
        end

        % Button pushed function: XZButton
        function XZButtonPushed(app, event)
            view(app.MLDataView, [0 0]);
        end

        % Button pushed function: YZButton
        function YZButtonPushed(app, event)
            view(app.MLDataView, [90 0]);
        end

        % Button pushed function: ModelsettingsButton
        function ModelsettingsButtonPushed(app, event)
            getModelSettings(app);
        end

        % Button pushed function: ClassifywithmodelButton
        function ClassifywithmodelButtonPushed(app, event)
            switch app.movie_data.models.current_model
                case "Random forest"
                    labelWithRF(app);

                case "Gradient boosted trees"
                    

                case "Gated Recurrent Unit"
                    
                    %this logic of looking to see if the model was trained
                    %in windowed mode should could be replaced with logic
                    %that instead looks at the size of the input layer and
                    %the longest trajectory; this may be useful in cases
                    %where a molecule longer than the input layer is
                    %present in the unseen data being labelled
                    if app.movie_data.models.GRU.windowed == true
                        labelWithGRUSlidingWindow(app);
                    elseif app.movie_data.models.GRU.windowed == false
                        labelWithGRU(app);
                    else
                        % << error: windowed nature was not recorded >>
                    end
                    
                    %compare labels to human classification
                    compareLabels(app);
                    
                case "Long Short-Term Memory"
                    if app.movie_data.models.LSTM.windowed == true
                        labelWithLSTMSlidingWindow(app);
                    elseif app.movie_data.models.LSTM.windowed == false
                        labelWithLSTM(app);
                    else
                        % << error: windowed nature was not recorded >>
                    end
                    
                    %compare labels to human classification
                    compareLabels(app);

                case "Bidirectional Long Short-Term Memory"
                    if app.movie_data.models.BiLSTM.windowed == true
                        labelWithBiLSTMSlidingWindow(app);
                    elseif app.movie_data.models.BiLSTM.windowed == false
                        labelWithBiLSTM(app);
                    else
                        % << error: windowed nature was not recorded >>
                    end
                    
                    %compare labels to human classification
                    compareLabels(app);

                otherwise
                
            end
        end

        % Button pushed function: SavemodelButton
        function SavemodelButtonPushed(app, event)
           %open save dialog
            [filename, pathname] = uiputfile({'*.mat', 'MAT-files (*.mat)'}, 'Save Model As');
            
            %check if the user pressed 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)
                app.textout.Value = "User pressed cancel";
            else
                full_filepath = fullfile(pathname, filename);
            
                %create a struct containing the model and its metadata
                switch app.movie_data.models.current_model
                    case "Random forest"
                        model_data = struct('model', app.movie_data.models.RF);
                    case "Gated Recurrent Unit"
                        model_data = struct('model', app.movie_data.models.GRU);
                    case "Long Short-Term Memory"
                        model_data = struct('model', app.movie_data.models.LSTM);
                    case "Bidirectional Long Short-Term Memory"
                        model_data = struct('model', app.movie_data.models.BiLSTM);
                    otherwise
                    
                end
                
                %save the struct to the file
                save(full_filepath, 'model_data');
                app.textout.Value = app.movie_data.models.current_model + " model saved to " + full_filepath;
            end
        end

        % Button pushed function: GeneratePDFsofallmoleculesButton
        function GeneratePDFsofallmoleculesButtonPushed(app, event)
            viewAllMols(app);
        end

        % Button pushed function: DisplayalloverlayedeventsButton
        function DisplayalloverlayedeventsButtonPushed(app, event)
            featureID       = 19;    %temporarily hardcoded; this feature is collected and plotted for all entries and exits - this should be extracted from struct (along with feature name), using GUI dropdown input
            feature_name    = 'Step size (nm)';
            display_range   = [app.VideostartrelativetoeventsSpinner.Value app.VideoendrelativetoeventsSpinner.Value];    %set range of time values over which to plot, and to generate video
            [data_in, data_out, t] = genEventOverlays(app.UIAxes_compiled_events,...
                app.movie_data.results.VisuallyLabelled.LabelledMols,...
                1, app.movie_data.params.frame_rate, featureID,...
                feature_name, app.EntriesorexitsDropDown.Value,...
                app.Overlayerstyle.Value, display_range,...
                app.EventhistogramvideoupperlimitSpinner.Value);
            
            %generate video if required
            if app.GeneratevideoCheckBox.Value
                %strip the zeros from either side of the aligned matrices; note that t is then different for the entries and exits
                [data_in, t_in_trimmed]     = trimAlignedMatrix(data_in, t);
                [data_out, t_out_trimmed]   = trimAlignedMatrix(data_out, t);
                
                %set parameters for histogram video generation - a future version may replace this with GUI input; perhaps with a pre-filled value to match the source video frame rate
                frame_delay = 0.2;      %time between frames of gif video
                
                %construct the full path with the subdirectory
                suggested_path = fullfile(app.movie_data.params.ffPath, 'Aligned event videos');
                
                if ~exist(suggested_path, 'dir')
                    mkdir(suggested_path);
                end
                
                %save file
                suggested_filename = string(app.movie_data.params.title) + " aligned event histogram video";
                [file, path] = uiputfile('*.gif', 'Save File As', fullfile(suggested_path, suggested_filename));
                
                %check if the user pressed cancel
                if isequal(file, 0) || isequal(path, 0)
                    disp('User pressed cancel.');
                else
                    file_pathname = fullfile(path, file);
                    
                    %generate, save, and display the aligned videos
                    if strcmp(app.EntriesorexitsDropDown.Value, "Entries")
                        genAlignedVideo(data_in, t_in_trimmed, display_range, app.EventhistogramvideoupperlimitSpinner.Value, app.EventhistogramvideobinwidthSpinner.Value, frame_delay, file_pathname);
                        web(file_pathname);
                    elseif strcmp(app.EntriesorexitsDropDown.Value, "Exits")
                        genAlignedVideo(data_out, t_out_trimmed, display_range, app.EventhistogramvideoupperlimitSpinner.Value, app.EventhistogramvideobinwidthSpinner.Value, frame_delay, file_pathname);
                        web(file_pathname);
                    end
                end
            end
        end

        % Value changed function: FilteragainstDropDown
        function FilteragainstDropDownValueChanged(app, event)
            app.movie_data.params.filter_against = app.FilteragainstDropDown.Value;
            app.textout.Value = strcat('Filtering against', {' '}, num2str(app.movie_data.params.filter_against));
        end

        % Value changed function: FiltertoleranceSpinner
        function FiltertoleranceSpinnerValueChanged(app, event)
            app.movie_data.params.filter_tolerance = app.FiltertoleranceSpinner.Value;
            app.textout.Value = strcat('Localisation filtering tolerance', {' '}, num2str(app.movie_data.params.filter_tolerance));
        end

        % Button pushed function: UpdatedescriptivestatisticsButton
        function UpdatedescriptivestatisticsButtonPushed(app, event)
            computeDescriptiveStats(app);
        end

        % Button pushed function: AddreferencelineButton
        function AddreferencelineButtonPushed(app, event)
            if isfield(app.movie_data.params, 'reference_lines')
                app.movie_data.params.reference_lines = cat(1, app.movie_data.params.reference_lines, app.ReferencelineEditField.Value);
            else
                app.movie_data.params.reference_lines = app.ReferencelineEditField.Value;
            end
            yline(app.UIAxes_event_labeller, app.movie_data.params.reference_lines, '--', string(app.movie_data.params.reference_lines) + "  ", 'Color', app.ReferencelinecolourDropDown.Value, 'LineWidth', app.ReferencelinethicknessSpinner.Value);
            
            setLabellerAxesRange(app);
        end

        % Button pushed function: ClearreferencelinesButton
        function ClearreferencelinesButtonPushed(app, event)
            app.movie_data.params = rmfield(app.movie_data.params, 'reference_lines');
            repopulateEventLabeller(app);
        end

        % Button pushed function: PlotalltrajectorylengthsButton
        function PlotalltrajectorylengthsButtonPushed(app, event)
            t_obs = [];   %should pre-alocate
            
            %loop over cells
            for ii = 1:size(app.movie_data.cellROI_data, 1)
                %loop over tracks
                track_IDs = unique(app.movie_data.cellROI_data(ii).tracks(:, 4));
                for jj = 1:size(track_IDs, 1)
                    t_obs = cat(1, t_obs, sum(app.movie_data.cellROI_data(ii).tracks(:, 4)==track_IDs(jj)));
                end
            end
            
            %apply frame rate, and plot histogram
            t_obs = t_obs ./ app.movie_data.params.frame_rate;
            h     = histogram(app.UIAxes_compiled_events, t_obs);
            %ylim(app.UIAxes_compiled_events, [0 max(t_obs)*1.1]);
            title(app.UIAxes_compiled_events, "Molecule observation times prior to filtering (N = " + num2str(size(t_obs, 1)) + ")");
            h.FaceColor = 'black';
            h.EdgeColor = 'white';
            h.LineWidth = 2;
        end

        % Button pushed function: StatetransitiondiagramButton
        function StatetransitiondiagramButtonPushed(app, event)
            [state_times, ~]        = computeTotalStateTimes(app.movie_data, 'VisuallyLabelled');
            [transition_rates, ~]   = computeTransitionRates(app.movie_data);
            drawStateDiagram(app.movie_data.params.class_names, state_times, transition_rates, app.movie_data.params.event_label_colours)
        end

        % Button pushed function: DisplaystepsizeprofileButton
        function DisplaystepsizeprofileButtonPushed(app, event)
            N_lim = app.MaximumstepsizesSpinner.Value;
            computeStepSizeProfile(app.movie_data, N_lim, app.UIAxes_compiled_events);
        end

        % Button pushed function: VisittheGitHubrepositoryButton
        function VisittheGitHubrepositoryButtonPushed(app, event)
            app.textout.Value = "Directing you to the GitHub repository";
            web('http://github.com/opambos', '-browser');
        end

        % Button pushed function: ClearanalysisButton
        function ClearanalysisButtonPushed(app, event)
            %return app to original state, and inform user of the need to load new data or analysis file
            
            %clear main struct
            app.movie_data = struct();
            
            %clear text fields
            app.CurrentlyloadeddatasetTextArea.Value    = "";
            app.FileoverviewTextArea.Value              = "";
            app.DataanalysisnotesTextArea.Value         = "";
            app.UserEditField.Value                     = "Default user";
            app.CellIDTextArea.Value                    = "";
            app.MolIDTextArea.Value                     = "";
            app.ReferencelineEditField.Value            = 0;
            app.CurrentmodelfileTextArea.Value          = "Internal or not loaded";
            app.DatasetforclassificationTextArea.Value  = "";
            
            %clear UIAxes
            cla(app.UIAxes_event_labeller);
            cla(app.UIAxes_compiled_events);
            cla(app.UIAxes_image_event_labeller);
            cla(app.UIAxes_event_labeller_mesh);
            cla(app.UIAxes_labelling_progress);
            cla(app.UIAxes_event_labeller_status);
            
            %clear table of descriptive stats
            app.DescriptivestatsTable.Data = [];

            %clear event labelling buttons
            delete(app.Event_label_buttons.Children);
            
            %clear ML features list
            a = app.MLfeatures.Children;
            a.delete;
            
            app.EventstoviewDropDown.Items  = {'Class names will appear here'};
            app.EventoverlayerSOI.Items     = {'Class names will appear here'};
            
            %notify user
            app.textout.Value = "All data has been cleared. Please start by loading new data or an existing analysis file.";
        end

        % Button pushed function: SaveanalysisButton
        function SaveanalysisButtonPushed(app, event)
            %save the current analysis to file
            [file,path] = uiputfile(strcat(app.movie_data.params.title, '_kinetics_analysis.mat'));
            movie_data  = app.movie_data;
            save([path file], 'movie_data');
        end

        % Value changed function: XLimLowSpinner
        function XLimLowSpinnerValueChanged(app, event)
            x_lo = app.XLimLowSpinner.Value;
            x_limits = xlim(app.MLDataView);
            xlim(app.MLDataView, [x_lo x_limits(2)]);
        end

        % Value changed function: XLimHighSpinner
        function XLimHighSpinnerValueChanged(app, event)
            x_hi = app.XLimHighSpinner.Value;
            x_limits = xlim(app.MLDataView);
            xlim(app.MLDataView, [x_limits(1) x_hi]);
        end

        % Value changed function: YLimLowSpinner
        function YLimLowSpinnerValueChanged(app, event)
            y_lo = app.YLimLowSpinner.Value;
            y_limits = ylim(app.MLDataView);
            ylim(app.MLDataView, [y_lo y_limits(2)]);
        end

        % Value changed function: YLimHighSpinner
        function YLimHighSpinnerValueChanged(app, event)
            y_hi = app.YLimHighSpinner.Value;
            y_limits = ylim(app.MLDataView);
            ylim(app.MLDataView, [y_limits(1) y_hi]);
        end

        % Value changed function: ZLimLowSpinner
        function ZLimLowSpinnerValueChanged(app, event)
            z_lo = app.ZLimLowSpinner.Value;
            z_limits = zlim(app.MLDataView);
            zlim(app.MLDataView, [z_lo z_limits(2)]);
        end

        % Value changed function: ZLimHighSpinner
        function ZLimHighSpinnerValueChanged(app, event)
            z_hi = app.ZLimHighSpinner.Value;
            z_limits = zlim(app.MLDataView);
            zlim(app.MLDataView, [z_limits(1) z_hi]);
        end

        % Button pushed function: LoadexistingmodelButton
        function LoadexistingmodelButtonPushed(app, event)
            %ask user to select model file
            [filename, pathname] = uigetfile({'*.mat', 'MAT-files (*.mat)'}, 'Load Model');
            
            %check if the user pressed 'Cancel'
            if isequal(filename, 0) || isequal(pathname, 0)
                app.textout.Value = "User pressed cancel";
            else
                full_filepath = fullfile(pathname, filename);

                %load the struct from the file
                loaded_data = load(full_filepath);

                %check if the loaded data contains required fields; if so load it into the struct overwriting any existing model
                if isfield(loaded_data, 'model_data') && isfield(loaded_data.model_data, 'model')
                    switch loaded_data.model_data.model.model_type
                        case "Gated Recurrent Unit"
                            app.movie_data.models.GRU = loaded_data.model_data.model;
                            app.movie_data.models.current_model = "Gated Recurrent Unit";
                        case "Random forest"
                            app.movie_data.models.RF = loaded_data.model_data.model;
                            app.movie_data.models.current_model = "Random forest";
                        case "Long Short-Term Memory"
                            app.movie_data.models.LSTM = loaded_data.model_data.model;
                            app.movie_data.models.current_model = "Long Short-Term Memory";
                        case "Bidirectional Long Short-Term Memory"
                            app.movie_data.models.BiLSTM = loaded_data.model_data.model;
                            app.movie_data.models.current_model = "Bidirectional Long Short-Term Memory";
                        case "Bidirectional Gated Recurrent Unit"
                            app.movie_data.models.BiGRU = loaded_data.model_data.model;
                            app.movie_data.models.current_model = "Bidirectional Gated Recurrent Unit";
                        otherwise
                            
                    end
                    
                    app.textout.Value = "Model loaded from " + full_filepath;
                else
                    app.textout.Value = "Invalid file format";
                end
            end
            
        end

        % Callback function: MLfeatures
        function MLfeaturesCheckedNodesChanged(app, event)
            identifyExcludedSteps(app);
        end

        % Button pushed function: MoreinfoButton
        function MoreinfoButtonPushed(app, event)
            plotMore(app);
        end

        % Selection change function: EventlabellersettingsTab
        function EventlabellersettingsTabSelectionChanged(app, event)
            selected_tab = app.EventlabellersettingsTab.SelectedTab;
            
            %code to run when user moves between tabs
            if strcmp(selected_tab.Title, 'Plot settings')
                
                %repopulate the features lists for user selection of all types of plotting (in the UIAxes and external additional info plot)
                if isprop(app, 'movie_data') && isfield(app.movie_data, 'params') && isfield(app.movie_data.params, 'column_titles')
                    %repopulate the list of features to plot inside the UIAxes
                    app.FeatureDropDown.Items = app.movie_data.params.column_titles.tracks(1:end-1);
                    
                    %repopulate the checklist for external plotting
                    a = app.PlotMoreFeatures.Children;
                    a.delete;
                    for ii = 1:(size(app.movie_data.params.column_titles.tracks,2) - 1)
                        features(ii) = uitreenode(app.PlotMoreFeatures);
                        features(ii).Text = string(app.movie_data.params.column_titles.tracks(ii));
                    end
                end
                
            end
        end

        % Value changed function: FeatureDropDown
        function FeatureDropDownValueChanged(app, event)
            repopulateEventLabeller(app);
        end

        % Value changed function: LinecolourDropDown
        function LinecolourDropDownValueChanged(app, event)
            new_colour = app.LinecolourDropDown.Value;

            %find the line object, and change it's colour
            line_obj = findobj(app.UIAxes_event_labeller, 'Tag', 'step_trace');
            
            if ~isempty(line_obj)
                line_obj.Color = new_colour;
            end
        end

        % Value changed function: LinethicknessSpinner
        function LinethicknessSpinnerValueChanged(app, event)
            new_thickness = app.LinethicknessSpinner.Value;

            %find the line object, and change the line thickness
            line_obj = findobj(app.UIAxes_event_labeller, 'Tag', 'step_trace');
            
            if ~isempty(line_obj)
                line_obj.LineWidth = new_thickness;
            end
        end

        % Value changed function: SetlowerlimttozeroCheckBox
        function SetlowerlimttozeroCheckBoxValueChanged(app, event)
            setLabellerAxesRange(app);
        end

        % Value changed function: IndicatorthicknessSpinner
        function IndicatorthicknessSpinnerValueChanged(app, event)
            line_obj = findobj(app.UIAxes_event_labeller, 'Tag', 'current_pos');
            line_obj.LineWidth = app.IndicatorthicknessSpinner.Value;
        end

        % Value changed function: ScalebarCheckBox
        function ScalebarCheckBoxValueChanged(app, event)
            regenerateTrackViewer(app);
        end

        % Value changed function: ScalebarlengthSpinner
        function ScalebarlengthSpinnerValueChanged(app, event)
            regenerateTrackViewer(app);
        end

        % Value changed function: ScalebarpositionDropDown
        function ScalebarpositionDropDownValueChanged(app, event)
            regenerateTrackViewer(app);
        end

        % Value changed function: ScalebarcolourDropDown
        function ScalebarcolourDropDownValueChanged(app, event)
            regenerateTrackViewer(app);
        end

        % Button pushed function: ComputeglobalfeatureimportanceButton
        function ComputeglobalfeatureimportanceButtonPushed(app, event)
            %prepare map of class names: silly and redundant as this is mapping the char arrays of integers of class names to numbers as a proxy for the
            %custom strings provided by the user. This will be resolved in a future version when I have more time available
            class_to_num = containers.Map(app.movie_data.models.RF.model.ClassNames, 1:numel(app.movie_data.models.RF.model.ClassNames));

            %define a function handle for predictions
            f = @(X) cell2mat(values(class_to_num, predict(app.movie_data.models.RF.model, X)));
            
            %extract just the training data without labels
            data_train = app.movie_data.results.train_data(:, 1:end-1);
            
            %create shapley object
            explainer = shapley(f, data_train);
            
            %select subset of the training data for SHAP value computation, and initialise a matrix to store results
            N_examples = app.NumberofexamplesSpinner.Value;
            data_subset = data_train(1:N_examples, :);
            all_shap_values = zeros(N_examples, numel(app.movie_data.models.RF.feature_names));
            
            h = waitbar(0, 'Computing SHAP values, this may take some time...');
            
            %turn off slow SHAP computation warning
            warning('off', 'stats:responsible:shapley:BigXSlowComputation');
            
            %loop over examples computing and storing SHAP values
            for ii = 1:N_examples
                explainer = fit(explainer, data_subset(ii, :));
                all_shap_values(ii, :) = explainer.ShapleyValues.ShapleyValue;
                
                waitbar(ii / N_examples, h, sprintf('Computing SHAP values... (%d%%)', ii*100/N_examples));
            end
            
            %turn warning back on
            warning('on', 'stats:responsible:shapley:BigXSlowComputation');
            
            close(h);
            
            %compute mean absolute SHAP values for each feature
            global_abs_shap_values = mean(abs(all_shap_values), 1);
            global_raw_shap_values = mean(all_shap_values, 1);
            
            %reset the figure if it contains existing data
            if isfield(app.movie_data.state, "data_viewer_content") && ~strcmp(app.movie_data.state.data_viewer_content, "SHAP")
                reset(app.MLDataView);
            end
            
            %plot horizontal bar graph of global SHAP values
            switch app.SHAPvaluedisplayDropDown.Value
                case "Absolute"
                    bh = barh(app.MLDataView, global_abs_shap_values);
                    xlabel(app.MLDataView, 'Mean absolute SHAP Value');
                case "Raw"
                    bh = barh(app.MLDataView, global_raw_shap_values);
                    xlabel(app.MLDataView, 'Mean raw SHAP Value');
                otherwise
                    
            end
            
            %plot style
            bh.FaceColor = [0.4 0.4 0.4];
            bh.EdgeColor = 'white';
            bh.LineWidth = 2;
            set(app.MLDataView, 'yticklabel', app.movie_data.models.RF.feature_names);
            ylabel(app.MLDataView, '');
            title(app.MLDataView, 'Global Feature Importance');
            
            %keep track of the state of the MLDataView UIAxes
            app.movie_data.state.data_viewer_content = 'SHAP';
            
            %store metrics for future access
            app.movie_data.models.RF.metrics.global_SHAP_abs = global_abs_shap_values;
            app.movie_data.models.RF.metrics.global_SHAP_raw = global_raw_shap_values;
            
            app.textout.Value = "Global SHAP values computed and displayed";
        end

        % Button pushed function: DisplaySHAPvaluetableButton
        function DisplaySHAPvaluetableButtonPushed(app, event)
            %display in a pop-up window a table with the SHAP values from the last time this was calculated
            if isfield(app.movie_data.models, 'RF') && isfield(app.movie_data.models.RF, 'metrics')
                if isfield(app.movie_data.models.RF.metrics, 'global_SHAP_raw') && isfield(app.movie_data.models.RF.metrics, 'global_SHAP_abs')
                    %construct and display table
                    table_data  = [app.movie_data.models.RF.feature_names', num2cell(app.movie_data.models.RF.metrics.global_SHAP_raw'), num2cell(app.movie_data.models.RF.metrics.global_SHAP_abs')];
                    popup_fig   = uifigure('Name', 'SHAP Values', 'Position', [100 100 600 400]);

                    uitable(popup_fig, 'Data', table_data, 'Position', [20 20 560 360], ...
                            'ColumnName', {'Feature', 'Raw SHAP', 'Absolute SHAP'}, ...
                            'ColumnWidth', {200, 'auto', 'auto'}, ...
                            'ColumnEditable', [false false false]);
                else
                    uialert(app.UIFigure, 'Please compute the SHAP values first.', 'SHAP values not computed');
                end
            else
                uialert(app.UIFigure, 'Please train a random forest model first.', 'Model not trained');
            end
        end

        % Button pushed function: EvaluateindividualframesButton
        function EvaluateindividualframesButtonPushed(app, event)
            explainIndividualFrame(app);
        end

        % Button pushed function: PerformcrossvalidationButton
        function PerformcrossvalidationButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InVivoKineticsUIFigure = uifigure('Visible', 'off');
            app.InVivoKineticsUIFigure.Name = 'In Vivo Kinetics';
            app.InVivoKineticsUIFigure.KeyPressFcn = createCallbackFcn(app, @InVivoKineticsUIFigureKeyPress, true);
            app.SideBarTabGroup = uitabgroup(app.InVivoKineticsUIFigure);
            app.LoadSaveTab = uitab(app.SideBarTabGroup);
            app.LoadSaveTab.Title = 'Load/Save';
            app.GridLayout14 = uigridlayout(app.LoadSaveTab);
            app.LoaddataButton = uibutton(app.GridLayout14, 'state');
            app.LoaddataButton.ValueChangedFcn = createCallbackFcn(app, @LoaddataButtonValueChanged, true);
            app.LoaddataButton.Text = 'Load data';
            app.LoadanalysisButton = uibutton(app.GridLayout14, 'push');
            app.LoadanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadanalysisButtonPushed, true);
            app.LoadanalysisButton.Text = 'Load analysis';
            app.CurrentlyloadeddatasetTextAreaLabel = uilabel(app.GridLayout14);
            app.CurrentlyloadeddatasetTextAreaLabel.Text = 'Currently loaded dataset';
            app.CurrentlyloadeddatasetTextArea = uitextarea(app.GridLayout14);
            app.FileoverviewTextAreaLabel = uilabel(app.GridLayout14);
            app.FileoverviewTextAreaLabel.Text = 'File overview';
            app.FileoverviewTextArea = uitextarea(app.GridLayout14);
            app.UserLabel = uilabel(app.GridLayout14);
            app.UserLabel.Text = 'User';
            app.UserEditField = uieditfield(app.GridLayout14, 'text');
            app.UserEditField.Value = 'Default user';
            app.LoadconfigurationButton = uibutton(app.GridLayout14, 'push');
            app.LoadconfigurationButton.Tooltip = {'Load a configuration file. This will populate options throughout the interface.'};
            app.LoadconfigurationButton.Text = 'Load configuration';
            app.SaveconfigurationButton = uibutton(app.GridLayout14, 'push');
            app.SaveconfigurationButton.Text = 'Save configuration';
            app.ClearanalysisButton = uibutton(app.GridLayout14, 'push');
            app.ClearanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ClearanalysisButtonPushed, true);
            app.ClearanalysisButton.Text = 'Clear analysis';
            app.SaveanalysisButton = uibutton(app.GridLayout14, 'push');
            app.SaveanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveanalysisButtonPushed, true);
            app.SaveanalysisButton.Text = 'Save analysis';
            app.PrepareTab = uitab(app.SideBarTabGroup);
            app.PrepareTab.Title = 'Prepare';
            app.GridLayout = uigridlayout(app.PrepareTab);
            app.PreparedataforkineticsanalysisButton = uibutton(app.GridLayout, 'state');
            app.PreparedataforkineticsanalysisButton.ValueChangedFcn = createCallbackFcn(app, @PreparedataforkineticsanalysisButtonValueChanged, true);
            app.PreparedataforkineticsanalysisButton.Tooltip = {'Performs trajectory filtering, construction of synthetic features, and formatting of the data for downstream analysis with manual user labelling and ML classification tasks.'};
            app.PreparedataforkineticsanalysisButton.Text = 'Prepare data for kinetics analysis';
            app.PreparedataforMLclassificationButton = uibutton(app.GridLayout, 'state');
            app.PreparedataforMLclassificationButton.Text = 'Prepare data for ML classification';
            app.PixelscalenmEditFieldLabel = uilabel(app.GridLayout);
            app.PixelscalenmEditFieldLabel.Text = 'Pixel scale (nm)';
            app.PixelscaleEditField = uieditfield(app.GridLayout, 'numeric');
            app.PixelscaleEditField.ValueChangedFcn = createCallbackFcn(app, @PixelscaleEditFieldValueChanged, true);
            app.PixelscaleEditField.Tooltip = {'The size of each pixel in the video.'; ''; 'Important: the value used here during data preparation will scale the entire downstream analysis, updating this later will not change the pixel scale of existing data.'};
            app.PixelscaleEditField.Value = 95.6;
            app.TrackbufferframesSpinnerLabel = uilabel(app.GridLayout);
            app.TrackbufferframesSpinnerLabel.Text = 'Track buffer (frames)';
            app.TrackbufferframesSpinner = uispinner(app.GridLayout);
            app.TrackbufferframesSpinner.ValueChangedFcn = createCallbackFcn(app, @TrackbufferframesSpinnerValueChanged, true);
            app.TrackbufferframesSpinner.Tooltip = {'Minimum number of empty frames between tracks in the same cell.'; ''; 'Trajectories spaced by less than this number will result in a conflict, which is resolved using the settings below.'};
            app.TrackbufferframesSpinner.Value = 3;
            app.IntensityboxpxSpinnerLabel = uilabel(app.GridLayout);
            app.IntensityboxpxSpinnerLabel.Text = 'Intensity box (px)';
            app.IntensityboxpxSpinner = uispinner(app.GridLayout);
            app.IntensityboxpxSpinner.ValueChangedFcn = createCallbackFcn(app, @IntensityboxpxSpinnerValueChanged, true);
            app.IntensityboxpxSpinner.Tooltip = {'Size of box to use for calculating spot intensities. The number is represents the distance between the localised point, and the edge of the box.'; ''; 'This parameter is important for datasets localised using a non-Gaussian fitting algorithm.'};
            app.IntensityboxpxSpinner.Value = 3;
            app.MinimumtracklengthSpinnerLabel = uilabel(app.GridLayout);
            app.MinimumtracklengthSpinnerLabel.Tooltip = {'Minimum length of trajectory measured by the number of localisations'};
            app.MinimumtracklengthSpinnerLabel.Text = 'Minimum track length';
            app.MinimumtracklengthSpinner = uispinner(app.GridLayout);
            app.MinimumtracklengthSpinner.ValueChangedFcn = createCallbackFcn(app, @MinimumtracklengthSpinnerValueChanged, true);
            app.MinimumtracklengthSpinner.Tooltip = {'Minimum length of trajectories. Trajectories shorter than this minimum length are filtered in the data preparation stage. Note that following filtering using the ''truncate'' method some trajectories may have shorter durations.'};
            app.MinimumtracklengthSpinner.Value = 20;
            app.MemoryparameterSpinnerLabel = uilabel(app.GridLayout);
            app.MemoryparameterSpinnerLabel.Text = 'Memory parameter';
            app.MemoryparameterSpinner = uispinner(app.GridLayout);
            app.MemoryparameterSpinner.ValueChangedFcn = createCallbackFcn(app, @MemoryparameterSpinnerValueChanged, true);
            app.MemoryparameterSpinner.Tooltip = {'The number of consecutive frames that can be missed from a trajectory without filtering.'; ''; 'A memory parameter of 1 is typical in single molecule tracking applications to account for the possilbility of blinking events.'};
            app.MemoryparameterSpinner.Value = 1;
            app.FilteringmethodDropDownLabel = uilabel(app.GridLayout);
            app.FilteringmethodDropDownLabel.Text = 'Filtering method';
            app.FilteringmethodDropDown = uidropdown(app.GridLayout);
            app.FilteringmethodDropDown.Items = {'truncate', 'eliminate', 'keep all'};
            app.FilteringmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @FilteringmethodDropDownValueChanged, true);
            app.FilteringmethodDropDown.Tooltip = {'Determines how the software handles temporal overlaps of molecules in the same cell. Options are,'; ''; '''truncate'': eliminates all frames from overlapping regions of tracks to retain only the regions for which a single molecule is present, splitting tracks if necessary.'; ''; '''eliminate'': removes all trajectories that fall within the track buffer of neighbouring trajectories.'; ''; '''keep all'': does not apply any filtering resulting from temporal overlaps of multiple molecules in the same cell.'};
            app.FilteringmethodDropDown.Value = 'truncate';
            app.FilteragainstDropDownLabel = uilabel(app.GridLayout);
            app.FilteragainstDropDownLabel.Text = 'Filter against';
            app.FilteragainstDropDown = uidropdown(app.GridLayout);
            app.FilteragainstDropDown.Items = {'localisations', 'tracks'};
            app.FilteragainstDropDown.ValueChangedFcn = createCallbackFcn(app, @FilteragainstDropDownValueChanged, true);
            app.FilteragainstDropDown.Tooltip = {'Determines whether to perform the filtering process against the list of other existing trajectories in the same cell, or against the full database of localisations, which includes those that did not form trajectories.'; ''; 'Filtering against localisations imposes a stricter criteria for trajectory conflict resolution.'};
            app.FilteragainstDropDown.Value = 'localisations';
            app.FiltertoleranceSpinnerLabel = uilabel(app.GridLayout);
            app.FiltertoleranceSpinnerLabel.Text = 'Filter tolerance';
            app.FiltertoleranceSpinner = uispinner(app.GridLayout);
            app.FiltertoleranceSpinner.ValueChangedFcn = createCallbackFcn(app, @FiltertoleranceSpinnerValueChanged, true);
            app.FiltertoleranceSpinner.Tooltip = {'Minimum number of conflicts (defined by the trajectory conflict resolution settings above) before a trajectory is filtered. A higher number here indicates a more tolerate approach to filtering, and may require the user to discard more molecules during the labelling process.'};
            app.IllustrationborderpxSpinnerLabel = uilabel(app.GridLayout);
            app.IllustrationborderpxSpinnerLabel.Text = 'Illustration border (px)';
            app.IllustrationborderSpinner = uispinner(app.GridLayout);
            app.IllustrationborderSpinner.ValueChangedFcn = createCallbackFcn(app, @IllustrationborderSpinnerValueChanged, true);
            app.IllustrationborderSpinner.Tooltip = {'Size of border (in pixels) around each cell''s ROI to use in illustrations.'};
            app.IllustrationborderSpinner.Value = 5;
            app.UndriftingmethodDropDownLabel = uilabel(app.GridLayout);
            app.UndriftingmethodDropDownLabel.Text = 'Undrifting method';
            app.UndriftingmethodDropDown = uidropdown(app.GridLayout);
            app.UndriftingmethodDropDown.Items = {'Mesh-based', 'Image correlation', 'Global adaptive'};
            app.UndriftingmethodDropDown.Value = 'Mesh-based';
            app.NotesTab = uitab(app.SideBarTabGroup);
            app.NotesTab.Title = 'Notes';
            app.GridLayout19 = uigridlayout(app.NotesTab);
            app.DataanalysisnotesTextAreaLabel = uilabel(app.GridLayout19);
            app.DataanalysisnotesTextAreaLabel.Text = 'Data analysis notes';
            app.DataanalysisnotesTextArea = uitextarea(app.GridLayout19);
            app.DataanalysisnotesTextArea.Tooltip = {'You can keep your data analysis notes here local to the file being used. When you save your analysis, these notes are stored in plain text inside the analysis file. They will be re-loaded when you next continue your work.'};
            app.HelpaboutTab = uitab(app.SideBarTabGroup);
            app.HelpaboutTab.Title = 'Help & about';
            app.GridLayout20 = uigridlayout(app.HelpaboutTab);
            app.OpenPDFmanualButton = uibutton(app.GridLayout20, 'push');
            app.OpenPDFmanualButton.ButtonPushedFcn = createCallbackFcn(app, @OpenPDFmanualButtonPushed, true);
            app.OpenPDFmanualButton.Text = 'Open PDF manual';
            app.Label = uilabel(app.GridLayout20);
            app.Label.Text = '';
            app.Authorship = uitextarea(app.GridLayout20);
            app.Authorship.Editable = 'off';
            app.Authorship.Value = {'This software was developed by Dr Oliver Pambos, November 2020.'; ''; 'For more information the author can be contacted via,'; ''; 'Dr Oliver Pambos'; 'Condensed Matter Physics'; 'Department of Physics'; 'University of Oxford'; 'United Kingdom'; 'OX4 1GE'; ''; 'The code is available for download via GitHub: github.com/opambos'; ''; ''};
            app.TextArea2 = uitextarea(app.GridLayout20);
            app.TextArea2.Editable = 'off';
            app.TextArea2.Value = {'THIS IS A PRE-RELEASE UNPUBLISHED VERSION; DO NOT DISTRIBUTE. BY USING THIS SOFTWARE YOU AGREE THAT YOU HAVE READ, UNDERSTOOD, AND ACCEPTED THE TERMS OF SERVICE AGREEMENT DISPLAYED ON LAUNCH.'; ''; 'This software is built for classification, segmentation, prediction, and data mining of single molecule superresolution microscopy data to gain insights into biological processes.'; ''; 'To view the quickstart guide, release notes, PDF manual, or video guide, please click the links below'; ''; 'Tooltips are provided throughout the software; if you are unsure about the meaning of a particular button or component, hover the mouse over it for two seconds and a description will appear.'};
            app.QuickstartguideButton = uibutton(app.GridLayout20, 'push');
            app.QuickstartguideButton.Text = 'Quickstart guide';
            app.VisittheGitHubrepositoryButton = uibutton(app.GridLayout20, 'push');
            app.VisittheGitHubrepositoryButton.ButtonPushedFcn = createCallbackFcn(app, @VisittheGitHubrepositoryButtonPushed, true);
            app.VisittheGitHubrepositoryButton.Text = 'Visit the GitHub repository';
            app.textout = uitextarea(app.InVivoKineticsUIFigure);
            app.textout.Editable = 'off';
            app.textout.Value = {'Welcome to the In Vivo Kinetics reduction and analysis tool.'; 'November 2020.'; ''; 'Developed by,'; ''; 'Dr Oliver Pambos'; 'Condensed Matter Physics'; 'Department of Physics'; 'University of Oxford'};
            app.MainTabGroup = uitabgroup(app.InVivoKineticsUIFigure);
            app.MainTabGroup.SelectionChangedFcn = createCallbackFcn(app, @MainTabGroupSelectionChanged, true);
            app.EventlabellerTab = uitab(app.MainTabGroup);
            app.EventlabellerTab.Title = 'Event labeller';
            app.GridLayout10 = uigridlayout(app.EventlabellerTab);
            app.UIAxes_event_labeller_mesh = uiaxes(app.GridLayout10);
            app.UIAxes_event_labeller_mesh.Visible = 'off';
            app.UIAxes_labelling_progress = uiaxes(app.GridLayout10);
            app.UIAxes_event_labeller_status = uiaxes(app.GridLayout10);
            app.UIAxes_image_event_labeller = uiaxes(app.GridLayout10);
            app.UIAxes_image_event_labeller.CLim = [0 255];
            app.UIAxes_image_event_labeller.Visible = 'off';
            app.UIAxes_event_labeller = uiaxes(app.GridLayout10);
            xlabel(app.UIAxes_event_labeller, 'Time (s)')
            ylabel(app.UIAxes_event_labeller, 'Step size (nm)')
            zlabel(app.UIAxes_event_labeller, 'Z')
            app.Slider_event_labeller = uislider(app.GridLayout10);
            app.Slider_event_labeller.MajorTicks = [];
            app.Slider_event_labeller.MajorTickLabels = {''};
            app.Slider_event_labeller.ValueChangingFcn = createCallbackFcn(app, @Slider_event_labellerValueChanging, true);
            app.Slider_event_labeller.MinorTicks = [];
            app.CellIDTextAreaLabel = uilabel(app.GridLayout10);
            app.CellIDTextAreaLabel.Text = 'Cell ID:';
            app.CellIDTextArea = uitextarea(app.GridLayout10);
            app.MolIDLabel = uilabel(app.GridLayout10);
            app.MolIDLabel.Text = 'Mol ID:';
            app.MolIDTextArea = uitextarea(app.GridLayout10);
            app.EventlabellersettingsTab = uitabgroup(app.GridLayout10);
            app.EventlabellersettingsTab.SelectionChangedFcn = createCallbackFcn(app, @EventlabellersettingsTabSelectionChanged, true);
            app.LabellingcontrolsTab = uitab(app.EventlabellersettingsTab);
            app.LabellingcontrolsTab.Title = 'Labelling controls';
            app.GridLayout11 = uigridlayout(app.LabellingcontrolsTab);
            app.Event_label_buttons = uipanel(app.GridLayout11);
            app.Event_label_buttons.Title = 'Label state selection';
            app.GotoendButton = uibutton(app.GridLayout11, 'push');
            app.GotoendButton.ButtonPushedFcn = createCallbackFcn(app, @GotoendButtonPushed, true);
            app.GotoendButton.Text = 'Go to (e)nd';
            app.UndolabelButton = uibutton(app.GridLayout11, 'push');
            app.UndolabelButton.ButtonPushedFcn = createCallbackFcn(app, @UndolabelButtonPushed, true);
            app.UndolabelButton.Text = 'Undo label';
            app.BeginButton = uibutton(app.GridLayout11, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.DiscardmoleculeButton = uibutton(app.GridLayout11, 'push');
            app.DiscardmoleculeButton.ButtonPushedFcn = createCallbackFcn(app, @DiscardmoleculeButtonPushed, true);
            app.DiscardmoleculeButton.Text = 'Discard molecule';
            app.IllustrationsettingsPanel = uipanel(app.GridLayout11);
            app.IllustrationsettingsPanel.Title = 'Illustration settings';
            app.GridLayout12 = uigridlayout(app.IllustrationsettingsPanel);
            app.SaveillustrationButton = uibutton(app.GridLayout12, 'push');
            app.SaveillustrationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveillustrationButtonPushed, true);
            app.SaveillustrationButton.Text = 'Save illustration';
            app.PlayexportedvideoCheckBox = uicheckbox(app.GridLayout12);
            app.PlayexportedvideoCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlayexportedvideoCheckBoxValueChanged, true);
            app.PlayexportedvideoCheckBox.Text = 'Play exported video';
            app.PlayexportedvideoCheckBox.Value = true;
            app.SavealldatawithvideoCheckBox = uicheckbox(app.GridLayout12);
            app.SavealldatawithvideoCheckBox.Text = 'Save all data with video';
            app.IllustrateafterlabellingCheckBox = uicheckbox(app.GridLayout12);
            app.IllustrateafterlabellingCheckBox.Text = 'Illustrate after labelling';
            app.SaveeveryviewedmoleculeCheckBox = uicheckbox(app.GridLayout12);
            app.SaveeveryviewedmoleculeCheckBox.Text = 'Save every viewed molecule';
            app.forwardButton = uibutton(app.GridLayout11, 'push');
            app.forwardButton.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forwardButton.Tooltip = {'Move forward one frame. Alternatively press the ''f'' key on the keyboard when focused on the figure.'};
            app.forwardButton.Text = '(f)orward';
            app.backwardButton = uibutton(app.GridLayout11, 'push');
            app.backwardButton.ButtonPushedFcn = createCallbackFcn(app, @backwardButtonPushed, true);
            app.backwardButton.Tooltip = {'Move backwards one frame. Alternatively press the ''d'' key on the keyboard when focused on the figure.'};
            app.backwardButton.Text = 'backwar(d)';
            app.MoreinfoButton = uibutton(app.GridLayout11, 'push');
            app.MoreinfoButton.ButtonPushedFcn = createCallbackFcn(app, @MoreinfoButtonPushed, true);
            app.MoreinfoButton.Text = 'More info';
            app.GotostartButton = uibutton(app.GridLayout11, 'push');
            app.GotostartButton.ButtonPushedFcn = createCallbackFcn(app, @GotostartButtonPushed, true);
            app.GotostartButton.Text = 'Go to (s)tart';
            app.PlotsettingsTab = uitab(app.EventlabellersettingsTab);
            app.PlotsettingsTab.Title = 'Plot settings';
            app.GridLayout21 = uigridlayout(app.PlotsettingsTab);
            app.LinethicknessSpinnerLabel = uilabel(app.GridLayout21);
            app.LinethicknessSpinnerLabel.Text = 'Line thickness';
            app.LinethicknessSpinner = uispinner(app.GridLayout21);
            app.LinethicknessSpinner.Step = 0.5;
            app.LinethicknessSpinner.Limits = [0 Inf];
            app.LinethicknessSpinner.ValueChangedFcn = createCallbackFcn(app, @LinethicknessSpinnerValueChanged, true);
            app.LinethicknessSpinner.Value = 1.5;
            app.LinecolourDropDownLabel = uilabel(app.GridLayout21);
            app.LinecolourDropDownLabel.Text = 'Line colour';
            app.LinecolourDropDown = uidropdown(app.GridLayout21);
            app.LinecolourDropDown.Items = {'black', 'red', 'blue', 'green', 'yellow', 'cyan'};
            app.LinecolourDropDown.ValueChangedFcn = createCallbackFcn(app, @LinecolourDropDownValueChanged, true);
            app.LinecolourDropDown.Value = 'black';
            app.FeatureDropDownLabel = uilabel(app.GridLayout21);
            app.FeatureDropDownLabel.Text = 'Feature';
            app.FeatureDropDown = uidropdown(app.GridLayout21);
            app.FeatureDropDown.Items = {'Step size (nm)'};
            app.FeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureDropDownValueChanged, true);
            app.FeatureDropDown.Value = 'Step size (nm)';
            app.PlotMoreFeatures = uitree(app.GridLayout21, 'checkbox');
            app.FeaturestoplotinexternalwindowLabel = uilabel(app.GridLayout21);
            app.FeaturestoplotinexternalwindowLabel.Text = 'Features to plot in external window';
            app.SetlowerlimttozeroCheckBox = uicheckbox(app.GridLayout21);
            app.SetlowerlimttozeroCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetlowerlimttozeroCheckBoxValueChanged, true);
            app.SetlowerlimttozeroCheckBox.Text = 'Set lower limt to zero';
            app.SetlowerlimttozeroCheckBox.Value = true;
            app.ReferencelineEditFieldLabel = uilabel(app.GridLayout21);
            app.ReferencelineEditFieldLabel.Tooltip = {'Enter the value of a refernce line to place on the plot, and press the button below. The units of the lines are the same as those currently displayed on the Y-axis.'};
            app.ReferencelineEditFieldLabel.Text = 'Reference line';
            app.ReferencelineEditField = uieditfield(app.GridLayout21, 'numeric');
            app.AddreferencelineButton = uibutton(app.GridLayout21, 'push');
            app.AddreferencelineButton.ButtonPushedFcn = createCallbackFcn(app, @AddreferencelineButtonPushed, true);
            app.AddreferencelineButton.Text = 'Add reference line';
            app.ClearreferencelinesButton = uibutton(app.GridLayout21, 'push');
            app.ClearreferencelinesButton.ButtonPushedFcn = createCallbackFcn(app, @ClearreferencelinesButtonPushed, true);
            app.ClearreferencelinesButton.Tooltip = {'Clear the reference lines from the plot. Note that this will also clear the current labelling state of the trajectory.'};
            app.ClearreferencelinesButton.Text = 'Clear reference lines';
            app.ReferencelinethicknessSpinnerLabel = uilabel(app.GridLayout21);
            app.ReferencelinethicknessSpinnerLabel.Text = 'Reference line thickness';
            app.ReferencelinethicknessSpinner = uispinner(app.GridLayout21);
            app.ReferencelinethicknessSpinner.Step = 0.5;
            app.ReferencelinethicknessSpinner.Limits = [0 Inf];
            app.ReferencelinethicknessSpinner.Value = 1;
            app.IndicatorthicknessSpinnerLabel = uilabel(app.GridLayout21);
            app.IndicatorthicknessSpinnerLabel.Text = 'Indicator thickness';
            app.IndicatorthicknessSpinner = uispinner(app.GridLayout21);
            app.IndicatorthicknessSpinner.Step = 0.5;
            app.IndicatorthicknessSpinner.Limits = [0 Inf];
            app.IndicatorthicknessSpinner.ValueChangedFcn = createCallbackFcn(app, @IndicatorthicknessSpinnerValueChanged, true);
            app.IndicatorthicknessSpinner.Value = 1.5;
            app.ReferencelinecolourDropDownLabel = uilabel(app.GridLayout21);
            app.ReferencelinecolourDropDownLabel.Text = 'Reference line colour';
            app.ReferencelinecolourDropDown = uidropdown(app.GridLayout21);
            app.ReferencelinecolourDropDown.Items = {'black', 'red', 'blue', 'green', 'yellow', 'cyan'};
            app.ReferencelinecolourDropDown.Value = 'red';
            app.VideodisplayTab = uitab(app.EventlabellersettingsTab);
            app.VideodisplayTab.Title = 'Video display';
            app.GridLayout22 = uigridlayout(app.VideodisplayTab);
            app.VideoupscalefactorSpinnerLabel = uilabel(app.GridLayout22);
            app.VideoupscalefactorSpinnerLabel.Text = 'Video upscale factor';
            app.VideoupscalefactorSpinner = uispinner(app.GridLayout22);
            app.VideoupscalefactorSpinner.ValueChangedFcn = createCallbackFcn(app, @VideoupscalefactorSpinnerValueChanged, true);
            app.VideoupscalefactorSpinner.Value = 4;
            app.ExportvideoframerateHzEditFieldLabel = uilabel(app.GridLayout22);
            app.ExportvideoframerateHzEditFieldLabel.Text = 'Export video frame rate (Hz)';
            app.ExportframerateEditField = uieditfield(app.GridLayout22, 'numeric');
            app.ExportframerateEditField.ValueChangedFcn = createCallbackFcn(app, @ExportframerateEditFieldValueChanged, true);
            app.ExportframerateEditField.Value = 5;
            app.DynamicrangelowEditFieldLabel = uilabel(app.GridLayout22);
            app.DynamicrangelowEditFieldLabel.Text = 'Dynamic range low';
            app.DynamicrangelowEditField = uieditfield(app.GridLayout22, 'numeric');
            app.DynamicrangelowEditField.ValueChangedFcn = createCallbackFcn(app, @DynamicrangelowEditFieldValueChanged, true);
            app.DynamicrangelowEditField.Tooltip = {'The lower limit of the dynamic range of the fluorescence movie shown in the event labeller.'; ''; 'Lower values will increase background noise in the image but display a larger dynamic range of data. Higher values risk losing information in the signal. Ideally this number should be set at a value that best separates the signal and noise in the image.'};
            app.DynamicrangelowEditField.Value = 525;
            app.DynamicrangehighEditFieldLabel = uilabel(app.GridLayout22);
            app.DynamicrangehighEditFieldLabel.Text = 'Dynamic range high';
            app.DynamicrangehighEditField = uieditfield(app.GridLayout22, 'numeric');
            app.DynamicrangehighEditField.ValueChangedFcn = createCallbackFcn(app, @DynamicrangehighEditFieldValueChanged, true);
            app.DynamicrangehighEditField.Tooltip = {'The upper limit of the dynamic range of the fluorescence movie shown in the event labeller.'; ''; 'Selecting values far above the signal maximum will make the signal harder to see above the background noise. Selecting values significantly below the signal will cause the signal to become saturated. Ideally this number should be set close to the maximum value of the signal.'};
            app.DynamicrangehighEditField.Value = 1200;
            app.OverlayopacityEditFieldLabel = uilabel(app.GridLayout22);
            app.OverlayopacityEditFieldLabel.Text = 'Overlay opacity (%)';
            app.OverlayopacityEditField = uieditfield(app.GridLayout22, 'numeric');
            app.OverlayopacityEditField.ValueChangedFcn = createCallbackFcn(app, @OverlayopacityEditFieldValueChanged, true);
            app.OverlayopacityEditField.Tooltip = {'Opacity of the brightfield image overlaid on top of fluorescence signal in illustrations'};
            app.OverlayopacityEditField.Value = 30;
            app.FluorescenceimageCheckBox = uicheckbox(app.GridLayout22);
            app.FluorescenceimageCheckBox.Text = 'Fluorescence image';
            app.BrightfieldoverlayCheckBox = uicheckbox(app.GridLayout22);
            app.BrightfieldoverlayCheckBox.Text = 'Brightfield overlay';
            app.DynamicrangemodeDropDownLabel = uilabel(app.GridLayout22);
            app.DynamicrangemodeDropDownLabel.Text = 'Dynamic range mode';
            app.DynamicrangemodeDropDown = uidropdown(app.GridLayout22);
            app.DynamicrangemodeDropDown.Items = {'Auto', 'Manual'};
            app.DynamicrangemodeDropDown.ValueChangedFcn = createCallbackFcn(app, @DynamicrangemodeDropDownValueChanged, true);
            app.DynamicrangemodeDropDown.Tooltip = {'How to compute the dynamic range of the fluorescence video. Options are,'; ''; '"Auto": software automatically computes sensible values that provide a view of most of the dynamic range across the video of this specific molecule. This mode will ignore the manually entered dynamic range values below.'; ''; '"Manual": overrides the automatically-generated dynamic range scale. Switching to manual mode will set the lower and upper limits of the dynamic range to the user-entered values below.'};
            app.DynamicrangemodeDropDown.Value = 'Auto';
            app.TrajectoryillustrationTab = uitab(app.EventlabellersettingsTab);
            app.TrajectoryillustrationTab.Title = 'Trajectory illustration';
            app.GridLayout26 = uigridlayout(app.TrajectoryillustrationTab);
            app.ScalebarCheckBox = uicheckbox(app.GridLayout26);
            app.ScalebarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScalebarCheckBoxValueChanged, true);
            app.ScalebarCheckBox.Tooltip = {'Add a scale bar to the trajectory plot'};
            app.ScalebarCheckBox.Text = 'Scale bar';
            app.ScalebarCheckBox.Value = true;
            app.ScalebarcolourDropDownLabel = uilabel(app.GridLayout26);
            app.ScalebarcolourDropDownLabel.Text = 'Scale bar colour';
            app.ScalebarcolourDropDown = uidropdown(app.GridLayout26);
            app.ScalebarcolourDropDown.Items = {'white', 'black', 'red', 'blue', 'green', 'yellow', 'cyan'};
            app.ScalebarcolourDropDown.ValueChangedFcn = createCallbackFcn(app, @ScalebarcolourDropDownValueChanged, true);
            app.ScalebarcolourDropDown.Tooltip = {'The colour of the scale bar and its label'};
            app.ScalebarcolourDropDown.Value = 'white';
            app.ScalebarpositionDropDownLabel = uilabel(app.GridLayout26);
            app.ScalebarpositionDropDownLabel.Text = 'Scale bar position';
            app.ScalebarpositionDropDown = uidropdown(app.GridLayout26);
            app.ScalebarpositionDropDown.Items = {'Top left', 'Top right', 'Bottom left', 'Bottom right'};
            app.ScalebarpositionDropDown.ValueChangedFcn = createCallbackFcn(app, @ScalebarpositionDropDownValueChanged, true);
            app.ScalebarpositionDropDown.Tooltip = {'The corner of the trajectory plot in which to place the scale bar.'};
            app.ScalebarpositionDropDown.Value = 'Bottom right';
            app.ScalebarlengthmSpinnerLabel = uilabel(app.GridLayout26);
            app.ScalebarlengthmSpinnerLabel.Text = 'Scale bar length (μm)';
            app.ScalebarlengthSpinner = uispinner(app.GridLayout26);
            app.ScalebarlengthSpinner.Step = 0.5;
            app.ScalebarlengthSpinner.Limits = [0 Inf];
            app.ScalebarlengthSpinner.ValueChangedFcn = createCallbackFcn(app, @ScalebarlengthSpinnerValueChanged, true);
            app.ScalebarlengthSpinner.Tooltip = {'Set the scale bar length in units of micrometers (μm)'};
            app.ScalebarlengthSpinner.Value = 1;
            app.TrackstyleDropDownLabel = uilabel(app.GridLayout26);
            app.TrackstyleDropDownLabel.Text = 'Track style';
            app.TrackstyleDropDown = uidropdown(app.GridLayout26);
            app.TrackstyleDropDown.Items = {'Colour by time', 'Single colour', 'Colour by classification'};
            app.TrackstyleDropDown.Value = 'Colour by time';
            app.AutosaveTab = uitab(app.EventlabellersettingsTab);
            app.AutosaveTab.Title = 'Autosave';
            app.GridLayout25 = uigridlayout(app.AutosaveTab);
            app.AutosavefrequencytrajectoriesSpinnerLabel = uilabel(app.GridLayout25);
            app.AutosavefrequencytrajectoriesSpinnerLabel.Text = 'Autosave frequency (trajectories)';
            app.AutosavefrequencytrajectoriesSpinner = uispinner(app.GridLayout25);
            app.AutosavefrequencytrajectoriesSpinner.Limits = [0 Inf];
            app.AutosavefrequencytrajectoriesSpinner.ValueDisplayFormat = '%.0f';
            app.AutosavefrequencytrajectoriesSpinner.Tooltip = {'Frequency for autosaving during manual annotation. The number here represents the number of trajectories labelled between autosave events.'; ''; 'To disable autosaving, set this number to zero.'};
            app.AutosavefrequencytrajectoriesSpinner.Value = 2;
            app.NumberofautosvefilestokeepSpinnerLabel = uilabel(app.GridLayout25);
            app.NumberofautosvefilestokeepSpinnerLabel.Text = 'Number of autosve files to keep';
            app.NumberofautosvefilestokeepSpinner = uispinner(app.GridLayout25);
            app.NumberofautosvefilestokeepSpinner.Limits = [0 Inf];
            app.NumberofautosvefilestokeepSpinner.Tooltip = {'The maximum number of autosave files to maintain. Only these most recent autosave files will be retained.'};
            app.NumberofautosvefilestokeepSpinner.Value = 3;
            app.InsightsTab = uitab(app.MainTabGroup);
            app.InsightsTab.Title = 'Insights';
            app.GridLayout2 = uigridlayout(app.InsightsTab);
            app.UIAxes_compiled_events = uiaxes(app.GridLayout2);
            title(app.UIAxes_compiled_events, 'Histogram of manually-labelled events')
            xlabel(app.UIAxes_compiled_events, 'Event duration (s)')
            ylabel(app.UIAxes_compiled_events, 'Number of events')
            app.TabGroup4 = uitabgroup(app.GridLayout2);
            app.DatasetoverviewTab = uitab(app.TabGroup4);
            app.DatasetoverviewTab.Title = 'Dataset overview';
            app.GridLayout3 = uigridlayout(app.DatasetoverviewTab);
            app.UpdatedescriptivestatisticsButton = uibutton(app.GridLayout3, 'push');
            app.UpdatedescriptivestatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatedescriptivestatisticsButtonPushed, true);
            app.UpdatedescriptivestatisticsButton.Text = 'Update descriptive statistics';
            app.DescriptivestatsTable = uitable(app.GridLayout3);
            app.DescriptivestatsTable.ColumnName = {'Parameter'; 'Value'};
            app.DescriptivestatsTable.RowName = {};
            app.StatetransitiondiagramButton = uibutton(app.GridLayout3, 'push');
            app.StatetransitiondiagramButton.ButtonPushedFcn = createCallbackFcn(app, @StatetransitiondiagramButtonPushed, true);
            app.StatetransitiondiagramButton.Text = 'State transition diagram';
            app.DisplaystepsizeprofileButton = uibutton(app.GridLayout3, 'push');
            app.DisplaystepsizeprofileButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaystepsizeprofileButtonPushed, true);
            app.DisplaystepsizeprofileButton.Tooltip = {'Display a diagram of the step sizes in the distribution'};
            app.DisplaystepsizeprofileButton.Text = 'Display step size profile';
            app.MaximumstepsizesSpinnerLabel = uilabel(app.GridLayout3);
            app.MaximumstepsizesSpinnerLabel.Text = 'Maximum step sizes';
            app.MaximumstepsizesSpinner = uispinner(app.GridLayout3);
            app.MaximumstepsizesSpinner.ValueDisplayFormat = '%.0f';
            app.EventhistogramsTab = uitab(app.TabGroup4);
            app.EventhistogramsTab.Title = 'Event histograms';
            app.GridLayout4 = uigridlayout(app.EventhistogramsTab);
            app.DisplaystatisticsButton = uibutton(app.GridLayout4, 'push');
            app.DisplaystatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaystatisticsButtonPushed, true);
            app.DisplaystatisticsButton.Text = 'Display statistics';
            app.ExporthistogramButton = uibutton(app.GridLayout4, 'push');
            app.ExporthistogramButton.ButtonPushedFcn = createCallbackFcn(app, @ExporthistogramButtonPushed, true);
            app.ExporthistogramButton.Tooltip = {'Exports the currently-displayed histogram data to a CSV file using the format selected above. The file is saved in a subdirectory of the directory containing the source fluorescence video files called ''saved histogram data''.'};
            app.ExporthistogramButton.Text = 'Export histogram';
            app.EventstoviewDropDownLabel = uilabel(app.GridLayout4);
            app.EventstoviewDropDownLabel.Text = 'Events to view';
            app.EventstoviewDropDown = uidropdown(app.GridLayout4);
            app.TruncationDropDownLabel = uilabel(app.GridLayout4);
            app.TruncationDropDownLabel.Text = 'Truncation';
            app.TruncationDropDown = uidropdown(app.GridLayout4);
            app.TruncationDropDown.Items = {'Singly-truncated', 'Doubly-truncated', 'All truncated', 'Full events', 'All events'};
            app.TruncationDropDown.Value = 'Singly-truncated';
            app.BinwidthsSpinnerLabel = uilabel(app.GridLayout4);
            app.BinwidthsSpinnerLabel.Text = 'Bin width (s)';
            app.BinwidthsSpinner = uispinner(app.GridLayout4);
            app.BinwidthsSpinner.ValueChangedFcn = createCallbackFcn(app, @BinwidthsSpinnerValueChanged, true);
            app.BinwidthsSpinner.Value = 2;
            app.PlotalltrajectorylengthsButton = uibutton(app.GridLayout4, 'push');
            app.PlotalltrajectorylengthsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotalltrajectorylengthsButtonPushed, true);
            app.PlotalltrajectorylengthsButton.Text = 'Plot all trajectory lengths';
            app.PlotallstepsizesButton = uibutton(app.GridLayout4, 'push');
            app.PlotallstepsizesButton.Text = 'Plot all step sizes';
            app.SourceDropDownLabel = uilabel(app.GridLayout4);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.GridLayout4);
            app.SourceDropDown.Items = {'Before filtering', 'After filtering', 'Only labelled mols', ''};
            app.SourceDropDown.Value = 'Before filtering';
            app.HistogramdatatoexportDropDownLabel = uilabel(app.GridLayout4);
            app.HistogramdatatoexportDropDownLabel.Text = 'Histogram data to export';
            app.HistogramdatatoexportDropDown = uidropdown(app.GridLayout4);
            app.HistogramdatatoexportDropDown.Items = {'Raw data', 'Only bins shown'};
            app.HistogramdatatoexportDropDown.Tooltip = {'Determines the format of the data to export'; ''; '''Raw data'' (recommended): this will export the underlying raw data that was used to generate the histogram shown; this allows for example other bin sizes to be used in external software.'; ''; '''Only bins shown'': this will export the centre and occupancy of each bin in the displayed histogram; this offers less flexibility, but does not require re-binning of data.'};
            app.HistogramdatatoexportDropDown.Value = 'Raw data';
            app.DiffusionanalysisTab = uitab(app.TabGroup4);
            app.DiffusionanalysisTab.Title = 'Diffusion analysis';
            app.GridLayout5 = uigridlayout(app.DiffusionanalysisTab);
            app.ComputeDButton = uibutton(app.GridLayout5, 'push');
            app.ComputeDButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeDButtonPushed, true);
            app.ComputeDButton.Tooltip = {'Compute the MSD for all labelled molecules and sections, across all states using the options above.'};
            app.ComputeDButton.Text = 'Compute D*';
            app.ExportMSDdataCheckBox = uicheckbox(app.GridLayout5);
            app.ExportMSDdataCheckBox.Tooltip = {'Save the computed MSD data to file for external plotting'};
            app.ExportMSDdataCheckBox.Text = 'Export MSD data';
            app.SourceoflabelleddataDropDownLabel = uilabel(app.GridLayout5);
            app.SourceoflabelleddataDropDownLabel.Text = 'Source of labelled data';
            app.SourceoflabelleddataDropDown = uidropdown(app.GridLayout5);
            app.SourceoflabelleddataDropDown.Items = {'Manually labelled'};
            app.SourceoflabelleddataDropDown.Tooltip = {'Source of labelled data to use for MSD calculation'};
            app.SourceoflabelleddataDropDown.Value = 'Manually labelled';
            app.MinimumlocalisationsforMSDSpinnerLabel = uilabel(app.GridLayout5);
            app.MinimumlocalisationsforMSDSpinnerLabel.Text = 'Minimum localisations for MSD';
            app.MinimumlocalisationsforMSDSpinner = uispinner(app.GridLayout5);
            app.MinimumlocalisationsforMSDSpinner.Limits = [1 Inf];
            app.MinimumlocalisationsforMSDSpinner.ValueDisplayFormat = '%.0f';
            app.MinimumlocalisationsforMSDSpinner.Tooltip = {'Minimum number of localisations in any given molecule or span in order to contribute to the MSD calculations'};
            app.MinimumlocalisationsforMSDSpinner.Value = 5;
            app.LagtimetodisplaysSpinnerLabel = uilabel(app.GridLayout5);
            app.LagtimetodisplaysSpinnerLabel.Text = 'Lag time to display (s)';
            app.LagtimetodisplaysSpinner = uispinner(app.GridLayout5);
            app.LagtimetodisplaysSpinner.Limits = [0 Inf];
            app.LagtimetodisplaysSpinner.Tooltip = {'Number of steps to display. Note that the MSDs are calculated for every possible step size in the dataset; this setting determines how many steps are actually displayed on the MSD plot'};
            app.LagtimetodisplaysSpinner.Value = 2;
            app.NumberofstepsforDcalculationSpinnerLabel = uilabel(app.GridLayout5);
            app.NumberofstepsforDcalculationSpinnerLabel.Text = 'Number of steps for D* calculation';
            app.NumberofstepsforDcalculationSpinner = uispinner(app.GridLayout5);
            app.NumberofstepsforDcalculationSpinner.Limits = [2 Inf];
            app.NumberofstepsforDcalculationSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofstepsforDcalculationSpinner.Tooltip = {'The number of lag time entries to use when computing D* for each state. It is important to limit this to only the first few points when to avoid the effect of anomolous diffusion.'};
            app.NumberofstepsforDcalculationSpinner.Value = 3;
            app.ProcessaveragesDropDownLabel = uilabel(app.GridLayout5);
            app.ProcessaveragesDropDownLabel.Text = 'Process averages';
            app.ProcessaveragesDropDown = uidropdown(app.GridLayout5);
            app.ProcessaveragesDropDown.Items = {'Molecule-by-molecule', 'All localisations'};
            app.ProcessaveragesDropDown.Tooltip = {'How to compute averages for each lag time. Molecule-by-molecule (recommended) uses a single entry from each trajectory or classified section. All localisations treats all localisations equally, which leads to bias towards longer trajectories.'};
            app.ProcessaveragesDropDown.Value = 'Molecule-by-molecule';
            app.DcalculationmethodDropDownLabel = uilabel(app.GridLayout5);
            app.DcalculationmethodDropDownLabel.Text = 'D* calculation method';
            app.DcalculationmethodDropDown = uidropdown(app.GridLayout5);
            app.DcalculationmethodDropDown.Items = {'MSD/4t', 'Correct for localisation error', '4D*'};
            app.DcalculationmethodDropDown.Tooltip = {'Method used to compute the apparent diffusion coefficient D* from the MSDs.'; ''; 'MSD/4t: this option will compute the gradient of a linear fit to the MSD - lag time plots for each state, dividing this by four (2*n, where n is the number of dimensions).'; ''; 'Correct for localisation error: this will compute D* from the (MSD/4t) - (sigma^2/t), where sigma is the localisation precision.'; ''; '4D*: The output is calculated as the gradient of the MSD-lag time plot, without dividing by 4.'};
            app.DcalculationmethodDropDown.Value = 'MSD/4t';
            app.LocalisationerrormSpinnerLabel = uilabel(app.GridLayout5);
            app.LocalisationerrormSpinnerLabel.Text = 'Localisation error (μm)';
            app.LocalisationerrormSpinner = uispinner(app.GridLayout5);
            app.LocalisationerrormSpinner.Limits = [0 Inf];
            app.LocalisationerrormSpinner.Tooltip = {'Localisation precision in the dataset. This is used is the apparent diffusion calculation when the ''localisation error corrected'' method for the D* calculation is selected. Localiastion precision is typically determined from the RMS of the errors in X and Y in a fixed sample. For most SMLM techniques these values are a few tens of nanometers.'};
            app.LocalisationerrormSpinner.Value = 0.04;
            app.GalleryviewerTab = uitab(app.TabGroup4);
            app.GalleryviewerTab.Title = 'Gallery viewer';
            app.GridLayout6 = uigridlayout(app.GalleryviewerTab);
            app.GeneratePDFsofallmoleculesButton = uibutton(app.GridLayout6, 'push');
            app.GeneratePDFsofallmoleculesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePDFsofallmoleculesButtonPushed, true);
            app.GeneratePDFsofallmoleculesButton.Text = 'Generate PDFs of all molecules';
            app.EventoverlayerTab = uitab(app.TabGroup4);
            app.EventoverlayerTab.Title = 'Event overlayer';
            app.GridLayout7 = uigridlayout(app.EventoverlayerTab);
            app.OverlayplotsettingsPanel = uipanel(app.GridLayout7);
            app.OverlayplotsettingsPanel.Title = 'Overlay plot settings';
            app.GridLayout8 = uigridlayout(app.OverlayplotsettingsPanel);
            app.overlayerylimhigh = uieditfield(app.GridLayout8, 'numeric');
            app.overlayerylimhigh.Value = 1000;
            app.overlayerxlimhigh = uieditfield(app.GridLayout8, 'numeric');
            app.overlayerxlimhigh.Value = 10;
            app.UpdatelimitsButton = uibutton(app.GridLayout8, 'push');
            app.UpdatelimitsButton.Text = 'Update limits';
            app.xaxisEditFieldLabel = uilabel(app.GridLayout8);
            app.xaxisEditFieldLabel.Text = 'x-axis';
            app.overlayerxlimlow = uieditfield(app.GridLayout8, 'numeric');
            app.overlayerxlimlow.Value = -10;
            app.yaxisEditFieldLabel = uilabel(app.GridLayout8);
            app.yaxisEditFieldLabel.Text = 'y-axis';
            app.overlayerylimlow = uieditfield(app.GridLayout8, 'numeric');
            app.StyleDropDownLabel = uilabel(app.GridLayout8);
            app.StyleDropDownLabel.Text = 'Style';
            app.Overlayerstyle = uidropdown(app.GridLayout8);
            app.Overlayerstyle.Items = {'Lines', 'Points'};
            app.Overlayerstyle.Value = 'Lines';
            app.DisplayalloverlayedeventsButton = uibutton(app.GridLayout7, 'push');
            app.DisplayalloverlayedeventsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayalloverlayedeventsButtonPushed, true);
            app.DisplayalloverlayedeventsButton.Text = 'Display all overlayed events';
            app.ExporteventoverlaysButton = uibutton(app.GridLayout7, 'push');
            app.ExporteventoverlaysButton.Text = 'Export event overlays';
            app.EventhistogramvideosettingsPanel = uipanel(app.GridLayout7);
            app.EventhistogramvideosettingsPanel.Title = 'Event histogram video settings';
            app.GridLayout9 = uigridlayout(app.EventhistogramvideosettingsPanel);
            app.EventhistogramvideobinwidthSpinnerLabel = uilabel(app.GridLayout9);
            app.EventhistogramvideobinwidthSpinnerLabel.Text = 'Event histogram video bin width';
            app.EventhistogramvideobinwidthSpinner = uispinner(app.GridLayout9);
            app.EventhistogramvideobinwidthSpinner.Value = 25;
            app.EventhistogramvideoupperlimitSpinnerLabel = uilabel(app.GridLayout9);
            app.EventhistogramvideoupperlimitSpinnerLabel.Text = 'Event histogram video upper limit';
            app.EventhistogramvideoupperlimitSpinner = uispinner(app.GridLayout9);
            app.EventhistogramvideoupperlimitSpinner.Value = 650;
            app.VideostartrelativetoeventsSpinnerLabel = uilabel(app.GridLayout9);
            app.VideostartrelativetoeventsSpinnerLabel.Text = 'Video start relative to event (s)';
            app.VideostartrelativetoeventsSpinner = uispinner(app.GridLayout9);
            app.VideostartrelativetoeventsSpinner.Value = -5;
            app.VideoendrelativetoeventsSpinnerLabel = uilabel(app.GridLayout9);
            app.VideoendrelativetoeventsSpinnerLabel.Text = 'Video end relative to event (s)';
            app.VideoendrelativetoeventsSpinner = uispinner(app.GridLayout9);
            app.VideoendrelativetoeventsSpinner.Value = 5;
            app.EntriesorexitsDropDownLabel = uilabel(app.GridLayout9);
            app.EntriesorexitsDropDownLabel.Text = 'Entries or exits?';
            app.EntriesorexitsDropDown = uidropdown(app.GridLayout9);
            app.EntriesorexitsDropDown.Items = {'Entries', 'Exits'};
            app.EntriesorexitsDropDown.Value = 'Entries';
            app.GeneratevideoCheckBox = uicheckbox(app.GridLayout9);
            app.GeneratevideoCheckBox.Text = 'Generate video';
            app.StateofinterestLabel = uilabel(app.GridLayout7);
            app.StateofinterestLabel.Text = 'State of interest';
            app.EventoverlayerSOI = uidropdown(app.GridLayout7);
            app.MLclassificationTab = uitab(app.MainTabGroup);
            app.MLclassificationTab.Title = 'ML classification';
            app.GridLayout13 = uigridlayout(app.MLclassificationTab);
            app.MLDataView = uiaxes(app.GridLayout13);
            title(app.MLDataView, 'No data plotted')
            xlabel(app.MLDataView, 'X')
            ylabel(app.MLDataView, 'Y')
            zlabel(app.MLDataView, 'Z')
            app.MLfeatures = uitree(app.GridLayout13, 'checkbox');
            app.MLfeatures.Tag = 'cbtree';
            % Assign Checked Nodes
            app.MLfeatures.CheckedNodesChangedFcn = createCallbackFcn(app, @MLfeaturesCheckedNodesChanged, true);
            app.FeaturesLabel = uilabel(app.GridLayout13);
            app.FeaturesLabel.Text = 'Features';
            app.MLControlTabs = uitabgroup(app.GridLayout13);
            app.MLControlTabs.SelectionChangedFcn = createCallbackFcn(app, @MLControlTabsSelectionChanged, true);
            app.GeneratetrainingdataTab = uitab(app.MLControlTabs);
            app.GeneratetrainingdataTab.Title = 'Generate training data';
            app.GridLayout15 = uigridlayout(app.GeneratetrainingdataTab);
            app.GeneratetrainingsetButton = uibutton(app.GridLayout15, 'push');
            app.GeneratetrainingsetButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratetrainingsetButtonPushed, true);
            app.GeneratetrainingsetButton.Tooltip = {'Generate a new training set from the labelled data using the features selected in the features selection list.'};
            app.GeneratetrainingsetButton.Text = 'Generate training set';
            app.ExporttrainingdataButton = uibutton(app.GridLayout15, 'push');
            app.ExporttrainingdataButton.Text = 'Export training data';
            app.KnockoutzerosCheckBox = uicheckbox(app.GridLayout15);
            app.KnockoutzerosCheckBox.Tooltip = {'Remove any rows containing zeros in the selected feature columns; this is currently only implemented for the Localisation-based shuffling method used for ensemble decision trees such as random forest models'};
            app.KnockoutzerosCheckBox.Text = 'Knock out zeros';
            app.ShufflingmethodLabel = uilabel(app.GridLayout15);
            app.ShufflingmethodLabel.Text = 'Shuffling method';
            app.ShufflingmethodDropDown = uidropdown(app.GridLayout15);
            app.ShufflingmethodDropDown.Items = {'Localisation', 'Molecule'};
            app.ShufflingmethodDropDown.Tooltip = {'The method used to shuffle the data, options are,'; ''; '"Localisation": individual localisations are treated as independent examples, and temporal information is encoded through feature engineering. This option produces a dataset consising of a single matrix. This option should be used when working with ensemble decision trees such as random forest models.'; ''; '"Molecule": trajectories remain intact, and their indices are shuffled, ensuring the random assignment of trajectories to the training, validation, and test datasets. This is the default option for use with neural network based models, such as Gated Recurrent Unit models.'};
            app.ShufflingmethodDropDown.Value = 'Localisation';
            app.TrainingvalidationtestsplitEditFieldLabel = uilabel(app.GridLayout15);
            app.TrainingvalidationtestsplitEditFieldLabel.Text = 'Training : validation : test split';
            app.TrainingvalidationtestsplitEditField = uieditfield(app.GridLayout15, 'text');
            app.TrainingvalidationtestsplitEditField.Tooltip = {'The proportions with which to split the labelled dataset. Enter three floating point numbers here separated by commas. The three values must sum to 1.'};
            app.TrainingvalidationtestsplitEditField.Value = '0.8, 0.1, 0.1';
            app.FeaturescalingDropDownLabel = uilabel(app.GridLayout15);
            app.FeaturescalingDropDownLabel.Text = 'Feature scaling';
            app.FeaturescalingDropDown = uidropdown(app.GridLayout15);
            app.FeaturescalingDropDown.Items = {'None', 'Z-score', 'Normalise (0-1)'};
            app.FeaturescalingDropDown.Tooltip = {'Select the Feature Scaling Method for Model Training & Classification:'; ''; '"None": No feature scaling applied. Ideal for ensemble decision trees like Random Forest, where feature scaling is typically not necessary.'; ''; '"Z-score": Standardizes features using the global mean and standard deviation from all localizations across all labeled trajectories. This transforms the data such that each feature has a mean of 0 and a standard deviation of 1. Recommended for most models, especially neural networks such as Gated Recurrent Unit (GRU) models, as it handles effectively features with different scales or units.'; ''; '"Normalise (0-1)": Scales each feature linearly to fit within a range from 0 to 1. While this can be beneficial for certain algorithms, it may not be suitable if the model encounters data beyond this range during classification. Use with caution if data variability is significant.'};
            app.FeaturescalingDropDown.Value = 'None';
            app.IgnorerowsfromstartSpinnerLabel = uilabel(app.GridLayout15);
            app.IgnorerowsfromstartSpinnerLabel.Text = 'Ignore rows from start';
            app.IgnorerowsfromstartSpinner = uispinner(app.GridLayout15);
            app.IgnorerowsfromstartSpinner.Limits = [0 Inf];
            app.IgnorerowsfromstartSpinner.ValueDisplayFormat = '%.0f';
            app.IgnorerowsfromstartSpinner.Tooltip = {'The number of steps to ignore at the start of each trajectory. Some specific features may not be present at the start or end of a trajectory.'; ''; 'Example: step size; as this represents the Euclidean distance between localisations there is no information encoded in the first frame, and the first row of every trajectory is therefore ignored from both the training and later classified data'; ''; 'Values here will update automatically when known features are selected by the user. When there is a need to override this setting (e.g. the user inputs an unknown feature name from a new localisation algorithm) enter a number of rows to ignore after selecting features, and before generating the training set.'};
            app.IgnorerowsfromendSpinnerLabel = uilabel(app.GridLayout15);
            app.IgnorerowsfromendSpinnerLabel.Text = 'Ignore rows from end';
            app.IgnorerowsfromendSpinner = uispinner(app.GridLayout15);
            app.IgnorerowsfromendSpinner.Limits = [0 Inf];
            app.IgnorerowsfromendSpinner.ValueDisplayFormat = '%.0f';
            app.IgnorerowsfromendSpinner.Tooltip = {'The number of steps to ignore at the end of each trajectory. Some specific features may not be present at the start or end of a trajectory.'; ''; 'Example: following step size; as this represents the Euclidean distance between the current localisation and the following localistion there is no information encoded in the final frame, and the final row of every trajectory is therefore ignored from both the training and later classified data'; ''; 'Values here will update automatically when known features are selected by the user. When there is a need to override this setting (e.g. the user inputs an unknown feature name from a new localisation algorithm) enter a number of rows to ignore after selecting features, and before generating the training set.'};
            app.SpantypeDropDownLabel = uilabel(app.GridLayout15);
            app.SpantypeDropDownLabel.Text = 'Span type';
            app.SpantypeDropDown = uidropdown(app.GridLayout15);
            app.SpantypeDropDown.Items = {'Whole trajectories', 'Segments', 'Sliding window'};
            app.SpantypeDropDown.Tooltip = {'Determines the span length of each trajectory that is used for training and classification.'; ''; 'Note that this only applies when using the molecule-wise shuffling method.'; ''; '"Whole trajectories": this involves sequence-to-sequence classification of each full trajectory. For all but the longest trajectory in the training set this introduces padding, and also produces a new feature which acts as a mask for the padded data.'; ''; '"Segments": the trajectory is separated into segments with the individual spans used for training and classification. This elimiinates the need for padding and masking, and also enables the model to focus on local/recent information.'; ''; '"Sliding window": each trajectory is replaced with a large number of small segments which are generated by moving a sliding window, frame-by-frame, from start to end.'};
            app.SpantypeDropDown.Value = 'Whole trajectories';
            app.SpanlengthSpinnerLabel = uilabel(app.GridLayout15);
            app.SpanlengthSpinnerLabel.Text = 'Span length';
            app.SpanlengthSpinner = uispinner(app.GridLayout15);
            app.SpanlengthSpinner.Limits = [0 Inf];
            app.SpanlengthSpinner.ValueDisplayFormat = '%.0f';
            app.SpanlengthSpinner.Value = 8;
            app.TrainmodelsTab = uitab(app.MLControlTabs);
            app.TrainmodelsTab.Title = 'Train models';
            app.GridLayout16 = uigridlayout(app.TrainmodelsTab);
            app.TrainMLmodelButton = uibutton(app.GridLayout16, 'push');
            app.TrainMLmodelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainMLmodelButtonPushed, true);
            app.TrainMLmodelButton.Tooltip = {'Train a machine learning model. Be sure to check you have already generated or loaded suitable training and test data for the model being trained.'};
            app.TrainMLmodelButton.Text = 'Train ML model';
            app.SavemodelButton = uibutton(app.GridLayout16, 'push');
            app.SavemodelButton.ButtonPushedFcn = createCallbackFcn(app, @SavemodelButtonPushed, true);
            app.SavemodelButton.Tooltip = {''};
            app.SavemodelButton.Text = 'Save model';
            app.ModelsettingsButton = uibutton(app.GridLayout16, 'push');
            app.ModelsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ModelsettingsButtonPushed, true);
            app.ModelsettingsButton.Tooltip = {'Change settings for the selected model type.'};
            app.ModelsettingsButton.Text = 'Model settings';
            app.ModeltypeDropDownLabel = uilabel(app.GridLayout16);
            app.ModeltypeDropDownLabel.Text = 'Model type';
            app.ModeltypeDropDown = uidropdown(app.GridLayout16);
            app.ModeltypeDropDown.Items = {'Random forest', 'Gradient boosted trees', 'Support Vector Machine (SVM)', 'Gated Recurrent Unit (GRU)', 'Long Short-Term Memory (LSTM)', 'Bidirectional LSTM (BiLSTM)', 'Temporal Convolutional Network (TCN)'};
            app.ModeltypeDropDown.Tooltip = {'Select the type of model to train. Options are,'; ''; '"Random forest"'; ''; '"Gated Recurrent Unit (GRU)"'};
            app.ModeltypeDropDown.Value = 'Random forest';
            app.ModelexportformatDropDownLabel = uilabel(app.GridLayout16);
            app.ModelexportformatDropDownLabel.Text = 'Model export format';
            app.ModelexportformatDropDown = uidropdown(app.GridLayout16);
            app.ModelexportformatDropDown.Items = {'TensorFlow', 'PyTorch'};
            app.ModelexportformatDropDown.Value = 'TensorFlow';
            app.ExportmodelButton = uibutton(app.GridLayout16, 'push');
            app.ExportmodelButton.Text = 'Export model';
            app.KfoldsSpinnerLabel = uilabel(app.GridLayout16);
            app.KfoldsSpinnerLabel.Text = 'K-folds';
            app.KfoldsSpinner = uispinner(app.GridLayout16);
            app.KfoldsSpinner.Limits = [0 Inf];
            app.KfoldsSpinner.ValueDisplayFormat = '%.0f';
            app.KfoldsSpinner.Value = 5;
            app.BootstrapsamplesSpinnerLabel = uilabel(app.GridLayout16);
            app.BootstrapsamplesSpinnerLabel.Text = 'Bootstrap samples';
            app.BootstrapsamplesSpinner = uispinner(app.GridLayout16);
            app.BootstrapsamplesSpinner.Limits = [0 Inf];
            app.BootstrapsamplesSpinner.ValueDisplayFormat = '%.0f';
            app.BootstrapsamplesSpinner.Value = 200;
            app.UsebootstrappingCheckBox = uicheckbox(app.GridLayout16);
            app.UsebootstrappingCheckBox.Text = 'Use bootstrapping';
            app.PerformcrossvalidationButton = uibutton(app.GridLayout16, 'push');
            app.PerformcrossvalidationButton.ButtonPushedFcn = createCallbackFcn(app, @PerformcrossvalidationButtonPushed, true);
            app.PerformcrossvalidationButton.Text = 'Perform cross-validation';
            app.VisualisationTab = uitab(app.MLControlTabs);
            app.VisualisationTab.Title = 'Visualisation';
            app.GridLayout17 = uigridlayout(app.VisualisationTab);
            app.UpdateButton = uibutton(app.GridLayout17, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Tooltip = {'Update the ML data viewer with the above chosen settings. If the maximum number of points displayed is smaller than the total number available, a random subset will be displayed each time you press this button.'};
            app.UpdateButton.Text = 'Update';
            app.XaxisDropDownLabel = uilabel(app.GridLayout17);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.GridLayout17);
            app.XaxisDropDown.Items = {'Feature 1', 'Feature 2', 'Feature 3'};
            app.XaxisDropDown.Tooltip = {'The selected feature to display along the X-axis'};
            app.XaxisDropDown.Value = 'Feature 1';
            app.YaxisDropDownLabel = uilabel(app.GridLayout17);
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.GridLayout17);
            app.YaxisDropDown.Items = {'Feature 1', 'Feature 2', 'Feature 3'};
            app.YaxisDropDown.Tooltip = {'The selected feature to display along the Y-axis'};
            app.YaxisDropDown.Value = 'Feature 2';
            app.ZaxisDropDownLabel = uilabel(app.GridLayout17);
            app.ZaxisDropDownLabel.Text = 'Z-axis';
            app.ZaxisDropDown = uidropdown(app.GridLayout17);
            app.ZaxisDropDown.Items = {'Feature 1', 'Feature 2', 'Feature 3'};
            app.ZaxisDropDown.Tooltip = {'The selected feature to display along the Z-axis'};
            app.ZaxisDropDown.Value = 'Feature 3';
            app.DatatodisplayDropDownLabel = uilabel(app.GridLayout17);
            app.DatatodisplayDropDownLabel.Text = 'Data to display';
            app.DatatodisplayDropDown = uidropdown(app.GridLayout17);
            app.DatatodisplayDropDown.Items = {'All user-classified data', 'Training data', 'Validation data', 'Test data', 'ML classified data'};
            app.DatatodisplayDropDown.Tooltip = {'This is the dataset to visualise in the ML data viewer'};
            app.DatatodisplayDropDown.Value = 'All user-classified data';
            app.PlotcontrolsPanel = uipanel(app.GridLayout17);
            app.PlotcontrolsPanel.Title = 'Plot controls';
            app.GridLayout23 = uigridlayout(app.PlotcontrolsPanel);
            app.RotateButton = uibutton(app.GridLayout23, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.ResetButton = uibutton(app.GridLayout23, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ZoomButton = uibutton(app.GridLayout23, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButtonPushed, true);
            app.ZoomButton.Text = 'Zoom';
            app.PanButton = uibutton(app.GridLayout23, 'push');
            app.PanButton.ButtonPushedFcn = createCallbackFcn(app, @PanButtonPushed, true);
            app.PanButton.Text = 'Pan';
            app.YZButton = uibutton(app.GridLayout23, 'push');
            app.YZButton.ButtonPushedFcn = createCallbackFcn(app, @YZButtonPushed, true);
            app.YZButton.Text = 'Y-Z';
            app.XZButton = uibutton(app.GridLayout23, 'push');
            app.XZButton.ButtonPushedFcn = createCallbackFcn(app, @XZButtonPushed, true);
            app.XZButton.Text = 'X-Z';
            app.XYButton = uibutton(app.GridLayout23, 'push');
            app.XYButton.ButtonPushedFcn = createCallbackFcn(app, @XYButtonPushed, true);
            app.XYButton.Text = 'X-Y';
            app.XLimLowSpinner = uispinner(app.GridLayout23);
            app.XLimLowSpinner.ValueDisplayFormat = '%6.4g';
            app.XLimLowSpinner.ValueChangedFcn = createCallbackFcn(app, @XLimLowSpinnerValueChanged, true);
            app.XLimHighSpinner = uispinner(app.GridLayout23);
            app.XLimHighSpinner.ValueDisplayFormat = '%6.4g';
            app.XLimHighSpinner.ValueChangedFcn = createCallbackFcn(app, @XLimHighSpinnerValueChanged, true);
            app.YLimLowSpinner = uispinner(app.GridLayout23);
            app.YLimLowSpinner.ValueDisplayFormat = '%6.4g';
            app.YLimLowSpinner.ValueChangedFcn = createCallbackFcn(app, @YLimLowSpinnerValueChanged, true);
            app.YLimHighSpinner = uispinner(app.GridLayout23);
            app.YLimHighSpinner.ValueDisplayFormat = '%6.4g';
            app.YLimHighSpinner.ValueChangedFcn = createCallbackFcn(app, @YLimHighSpinnerValueChanged, true);
            app.ZLimLowSpinner = uispinner(app.GridLayout23);
            app.ZLimLowSpinner.ValueDisplayFormat = '%6.4g';
            app.ZLimLowSpinner.ValueChangedFcn = createCallbackFcn(app, @ZLimLowSpinnerValueChanged, true);
            app.ZLimHighSpinner = uispinner(app.GridLayout23);
            app.ZLimHighSpinner.ValueDisplayFormat = '%6.4g';
            app.ZLimHighSpinner.ValueChangedFcn = createCallbackFcn(app, @ZLimHighSpinnerValueChanged, true);
            app.XaxisLabel = uilabel(app.GridLayout23);
            app.XaxisLabel.Text = 'X-axis';
            app.YaxisLabel = uilabel(app.GridLayout23);
            app.YaxisLabel.Text = 'Y-axis';
            app.ZaxisLabel = uilabel(app.GridLayout23);
            app.ZaxisLabel.Text = 'Z-axis';
            app.MaxpointstodisplaySpinnerLabel = uilabel(app.GridLayout17);
            app.MaxpointstodisplaySpinnerLabel.Text = 'Max points to display';
            app.MaxpointstodisplaySpinner = uispinner(app.GridLayout17);
            app.MaxpointstodisplaySpinner.Limits = [1 Inf];
            app.MaxpointstodisplaySpinner.ValueDisplayFormat = '%.0f';
            app.MaxpointstodisplaySpinner.Tooltip = {'The maximum number of points to display. Entering a number here smaller than the size of the dataset instructs the software to select a random subset of molecules'};
            app.MaxpointstodisplaySpinner.Value = 1000;
            app.ExplainabilityTab = uitab(app.MLControlTabs);
            app.ExplainabilityTab.Title = 'Explainability';
            app.GridLayout24 = uigridlayout(app.ExplainabilityTab);
            app.ComputeglobalfeatureimportanceButton = uibutton(app.GridLayout24, 'push');
            app.ComputeglobalfeatureimportanceButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeglobalfeatureimportanceButtonPushed, true);
            app.ComputeglobalfeatureimportanceButton.Text = 'Compute global feature importance';
            app.NumberofexamplesSpinnerLabel = uilabel(app.GridLayout24);
            app.NumberofexamplesSpinnerLabel.Text = 'Number of examples';
            app.NumberofexamplesSpinner = uispinner(app.GridLayout24);
            app.NumberofexamplesSpinner.Limits = [0 Inf];
            app.NumberofexamplesSpinner.ValueDisplayFormat = '%.0f';
            app.NumberofexamplesSpinner.Value = 20;
            app.SHAPvaluedisplayDropDownLabel = uilabel(app.GridLayout24);
            app.SHAPvaluedisplayDropDownLabel.Text = 'SHAP value display';
            app.SHAPvaluedisplayDropDown = uidropdown(app.GridLayout24);
            app.SHAPvaluedisplayDropDown.Items = {'Absolute', 'Raw'};

            app.SHAPvaluedisplayDropDown.Value = 'Absolute';
            app.DisplaySHAPvaluetableButton = uibutton(app.GridLayout24, 'push');
            app.DisplaySHAPvaluetableButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySHAPvaluetableButtonPushed, true);
            app.DisplaySHAPvaluetableButton.Text = 'Display SHAP value table';
            app.EvaluateindividualframesButton = uibutton(app.GridLayout24, 'push');
            app.EvaluateindividualframesButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateindividualframesButtonPushed, true);
            app.EvaluateindividualframesButton.Text = 'Evaluate individual frames';
            app.ClassifyusingmodelTab = uitab(app.MLControlTabs);
            app.ClassifyusingmodelTab.Title = 'Classify using model';
            app.GridLayout18 = uigridlayout(app.ClassifyusingmodelTab);
            app.LoadexistingmodelButton = uibutton(app.GridLayout18, 'push');
            app.LoadexistingmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadexistingmodelButtonPushed, true);
            app.LoadexistingmodelButton.Tooltip = {'Load a saved pre-trained model.'; ''; 'It is important that all of the features in the trained model exist in the new data to be classified. '; ''; 'Note that if you select a standard IVK saved analysis file this will import the most recently trained model before the file was saved.'};
            app.LoadexistingmodelButton.Text = 'Load existing model';
            app.ClassifywithmodelButton = uibutton(app.GridLayout18, 'push');
            app.ClassifywithmodelButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifywithmodelButtonPushed, true);
            app.ClassifywithmodelButton.Text = 'Classify with model';
            app.LoaddatasetforclassificationButton = uibutton(app.GridLayout18, 'push');
            app.LoaddatasetforclassificationButton.Tooltip = {'Load a new kinetics file. Note that this will overwrite any unsaved classifications in the current file'};
            app.LoaddatasetforclassificationButton.Text = 'Load dataset for classification';
            app.DisplaymodeldetailsButton = uibutton(app.GridLayout18, 'push');
            app.DisplaymodeldetailsButton.Text = 'Display model details';
            app.DisplaydatasetoverviewButton = uibutton(app.GridLayout18, 'push');
            app.DisplaydatasetoverviewButton.Text = 'Display dataset overview';
            app.CurrentmodelfileTextAreaLabel = uilabel(app.GridLayout18);
            app.CurrentmodelfileTextAreaLabel.Text = 'Current model file:';
            app.CurrentmodelfileTextArea = uitextarea(app.GridLayout18);
            app.CurrentmodelfileTextArea.Value = {'Internal or not loaded'};
            app.DatasetforclassificationTextAreaLabel = uilabel(app.GridLayout18);
            app.DatasetforclassificationTextAreaLabel.Text = 'Dataset for classification:';
            app.DatasetforclassificationTextArea = uitextarea(app.GridLayout18);
            app.EnsemblelearningTab = uitab(app.MLControlTabs);
            app.EnsemblelearningTab.Title = 'Ensemble learning';
            app.GridLayout27 = uigridlayout(app.EnsemblelearningTab);
            % Show the figure after all components are created
            app.InVivoKineticsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InVivoKineticsGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InVivoKineticsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InVivoKineticsUIFigure)
        end
    end
end